classdef Masters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        LoadDatasetTab                  matlab.ui.container.Tab
        DatasetEditField                matlab.ui.control.EditField
        DatasetEditFieldLabel           matlab.ui.control.Label
        LoadSimulatorButton             matlab.ui.control.Button
        InitialiseSimulationTab         matlab.ui.container.Tab
        CopyButton                      matlab.ui.control.Button
        InitialiseFilterPanel           matlab.ui.container.Panel
        InitialStatesPanel_2            matlab.ui.container.Panel
        degsLabel_11                    matlab.ui.control.Label
        CameraYawRateEditField_2        matlab.ui.control.NumericEditField
        CameraYawRateEditField_2Label   matlab.ui.control.Label
        degsLabel_10                    matlab.ui.control.Label
        CameraPitchRateEditField_2      matlab.ui.control.NumericEditField
        CameraPitchRateEditField_2Label  matlab.ui.control.Label
        degsLabel_9                     matlab.ui.control.Label
        CameraRollRateEditField_2       matlab.ui.control.NumericEditField
        CameraRollRateEditField_2Label  matlab.ui.control.Label
        InitialAngularVelocityLabel_2   matlab.ui.control.Label
        degLabel_11                     matlab.ui.control.Label
        CameraYawEditField_2            matlab.ui.control.NumericEditField
        CameraYawEditField_2Label       matlab.ui.control.Label
        degLabel_10                     matlab.ui.control.Label
        CameraPitchEditField_2          matlab.ui.control.NumericEditField
        CameraPitchEditField_2Label     matlab.ui.control.Label
        degLabel_9                      matlab.ui.control.Label
        CameraRollEditField_2           matlab.ui.control.NumericEditField
        CameraRollEditField_2Label      matlab.ui.control.Label
        InitialRotationLabel_2          matlab.ui.control.Label
        degLabel_12                     matlab.ui.control.Label
        OrbitInclinationEditField_2     matlab.ui.control.NumericEditField
        OrbitInclinationEditField_2Label  matlab.ui.control.Label
        kmLabel_4                       matlab.ui.control.Label
        AltitudeEditField_2             matlab.ui.control.NumericEditField
        AltitudeEditField_2Label        matlab.ui.control.Label
        degLabel_8                      matlab.ui.control.Label
        LongitudeEditField_2            matlab.ui.control.NumericEditField
        LongitudeEditField_2Label       matlab.ui.control.Label
        degLabel_7                      matlab.ui.control.Label
        LattitudeEditField_2            matlab.ui.control.NumericEditField
        LattitudeEditField_2Label       matlab.ui.control.Label
        InitialPositionandVelocityLabel_2  matlab.ui.control.Label
        ConstantsPanel_2                matlab.ui.container.Panel
        kgtimesm2Label_6                matlab.ui.control.Label
        I_zzEditField_2                 matlab.ui.control.NumericEditField
        I_zzLabel                       matlab.ui.control.Label
        kgtimesm2Label_5                matlab.ui.control.Label
        I_yyEditField_2                 matlab.ui.control.NumericEditField
        I_yyEditField_2Label            matlab.ui.control.Label
        kgtimesm2Label_4                matlab.ui.control.Label
        I_xxEditField_2                 matlab.ui.control.NumericEditField
        I_xxEditField_2Label            matlab.ui.control.Label
        degsLabel_8                     matlab.ui.control.Label
        J_2EditField_2                  matlab.ui.control.NumericEditField
        omega_eEditField_2              matlab.ui.control.NumericEditField
        J_2EditField_2Label             matlab.ui.control.Label
        omega_eEditField_2Label         matlab.ui.control.Label
        kmLabel_3                       matlab.ui.control.Label
        R_eEditField_2                  matlab.ui.control.NumericEditField
        R_eEditField_2Label             matlab.ui.control.Label
        km3s2Label_2                    matlab.ui.control.Label
        mu_eEditField_2                 matlab.ui.control.NumericEditField
        mu_eEditField_2Label            matlab.ui.control.Label
        HzLabel_2                       matlab.ui.control.Label
        DeltatEditField_2               matlab.ui.control.NumericEditField
        DeltatEditField_2Label          matlab.ui.control.Label
        InitialiseSatellitePanel        matlab.ui.container.Panel
        InitialStatesPanel              matlab.ui.container.Panel
        degsLabel_4                     matlab.ui.control.Label
        CameraYawRateEditField          matlab.ui.control.NumericEditField
        CameraYawRateEditFieldLabel     matlab.ui.control.Label
        degsLabel_3                     matlab.ui.control.Label
        CameraPitchRateEditField        matlab.ui.control.NumericEditField
        CameraPitchRateEditFieldLabel   matlab.ui.control.Label
        degsLabel_2                     matlab.ui.control.Label
        CameraRollRateEditField         matlab.ui.control.NumericEditField
        CameraRollRateEditFieldLabel    matlab.ui.control.Label
        InitialAngularVelocityLabel     matlab.ui.control.Label
        degLabel_5                      matlab.ui.control.Label
        CameraYawEditField              matlab.ui.control.NumericEditField
        CameraYawEditFieldLabel         matlab.ui.control.Label
        degLabel_4                      matlab.ui.control.Label
        CameraPitchEditField            matlab.ui.control.NumericEditField
        CameraPitchEditFieldLabel       matlab.ui.control.Label
        degLabel_3                      matlab.ui.control.Label
        CameraRollEditField             matlab.ui.control.NumericEditField
        CameraRollEditFieldLabel        matlab.ui.control.Label
        InitialRotationLabel            matlab.ui.control.Label
        degLabel_6                      matlab.ui.control.Label
        OrbitInclinationEditField       matlab.ui.control.NumericEditField
        OrbitInclinationEditFieldLabel  matlab.ui.control.Label
        kmLabel_2                       matlab.ui.control.Label
        AltitudeEditField               matlab.ui.control.NumericEditField
        AltitudeEditFieldLabel          matlab.ui.control.Label
        degLabel_2                      matlab.ui.control.Label
        LongitudeEditField              matlab.ui.control.NumericEditField
        LongitudeEditFieldLabel         matlab.ui.control.Label
        degLabel                        matlab.ui.control.Label
        LattitudeEditField              matlab.ui.control.NumericEditField
        LattitudeEditFieldLabel         matlab.ui.control.Label
        InitialPositionandVelocityLabel  matlab.ui.control.Label
        ConstantsPanel                  matlab.ui.container.Panel
        kgtimesm2Label_3                matlab.ui.control.Label
        kgtimesm2Label_2                matlab.ui.control.Label
        I_zzEditField                   matlab.ui.control.NumericEditField
        I_yyEditField                   matlab.ui.control.NumericEditField
        I_zzEditFieldLabel              matlab.ui.control.Label
        I_yyEditFieldLabel              matlab.ui.control.Label
        kgtimesm2Label                  matlab.ui.control.Label
        I_xxEditField                   matlab.ui.control.NumericEditField
        I_xxEditFieldLabel              matlab.ui.control.Label
        J_2EditField                    matlab.ui.control.NumericEditField
        J_2EditFieldLabel               matlab.ui.control.Label
        degsLabel                       matlab.ui.control.Label
        omega_eEditField                matlab.ui.control.NumericEditField
        omega_eEditFieldLabel           matlab.ui.control.Label
        kmLabel                         matlab.ui.control.Label
        R_eEditField                    matlab.ui.control.NumericEditField
        R_eEditFieldLabel               matlab.ui.control.Label
        km3s2Label                      matlab.ui.control.Label
        mu_eEditField                   matlab.ui.control.NumericEditField
        mu_eEditFieldLabel              matlab.ui.control.Label
        HzLabel                         matlab.ui.control.Label
        DeltatEditField                 matlab.ui.control.NumericEditField
        DeltatEditFieldLabel            matlab.ui.control.Label
        SimulationParametersPanel       matlab.ui.container.Panel
        sLabel_2                        matlab.ui.control.Label
        SimulationTimeEditField         matlab.ui.control.NumericEditField
        SimulationTimeEditFieldLabel    matlab.ui.control.Label
        InitiliaseCameraTab             matlab.ui.container.Tab
        CopyButton_2                    matlab.ui.control.Button
        FeatureDetectionPanel           matlab.ui.container.Panel
        FeatureDetectionMethodButtonGroup  matlab.ui.container.ButtonGroup
        ORBButton                       matlab.ui.control.ToggleButton
        SURFButton                      matlab.ui.control.ToggleButton
        SIFTButton                      matlab.ui.control.ToggleButton
        NumberofFeaturesEditField       matlab.ui.control.NumericEditField
        NumberofFeaturesEditFieldLabel  matlab.ui.control.Label
        LensDistortionsPanel            matlab.ui.container.Panel
        BEditField                      matlab.ui.control.NumericEditField
        BEditFieldLabel                 matlab.ui.control.Label
        GEditField                      matlab.ui.control.NumericEditField
        GEditFieldLabel                 matlab.ui.control.Label
        REditField                      matlab.ui.control.NumericEditField
        REditFieldLabel                 matlab.ui.control.Label
        p2EditField                     matlab.ui.control.NumericEditField
        p2EditFieldLabel                matlab.ui.control.Label
        p1EditField                     matlab.ui.control.NumericEditField
        p1EditFieldLabel                matlab.ui.control.Label
        k3EditField                     matlab.ui.control.NumericEditField
        k3EditFieldLabel                matlab.ui.control.Label
        k2EditField                     matlab.ui.control.NumericEditField
        k2EditFieldLabel                matlab.ui.control.Label
        k1EditField                     matlab.ui.control.NumericEditField
        k1EditFieldLabel                matlab.ui.control.Label
        ChromaticCheckBox               matlab.ui.control.CheckBox
        TangentialCheckBox              matlab.ui.control.CheckBox
        RadialCheckBox                  matlab.ui.control.CheckBox
        InitialiseETCameraPanel         matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        AlphaEditField_2                matlab.ui.control.NumericEditField
        AlphaEditFieldLabel             matlab.ui.control.Label
        mLabel_7                        matlab.ui.control.Label
        PixelSizeEditField_2            matlab.ui.control.NumericEditField
        PixelSizeEditField_2Label       matlab.ui.control.Label
        mLabel_6                        matlab.ui.control.Label
        FocalLengthEditField_2          matlab.ui.control.NumericEditField
        FocalLengthEditField_2Label     matlab.ui.control.Label
        pLabel_4                        matlab.ui.control.Label
        ResolutionHeightEditField_2     matlab.ui.control.NumericEditField
        ResolutionHeightEditField_2Label  matlab.ui.control.Label
        pLabel_3                        matlab.ui.control.Label
        ResolutionWidthEditField_2      matlab.ui.control.NumericEditField
        ResolutionWidthEditField_2Label  matlab.ui.control.Label
        InitialiseCameraPanel           matlab.ui.container.Panel
        AlphaEditField                  matlab.ui.control.NumericEditField
        AlphaEditField_2Label           matlab.ui.control.Label
        mLabel_5                        matlab.ui.control.Label
        PixelSizeEditField              matlab.ui.control.NumericEditField
        PixelSizeEditFieldLabel         matlab.ui.control.Label
        mLabel_4                        matlab.ui.control.Label
        FocalLengthEditField            matlab.ui.control.NumericEditField
        FocalLengthEditFieldLabel       matlab.ui.control.Label
        pLabel_2                        matlab.ui.control.Label
        ResolutionHeightEditField       matlab.ui.control.NumericEditField
        ResolutionHeightEditFieldLabel  matlab.ui.control.Label
        pLabel                          matlab.ui.control.Label
        ResolutionWidthEditField        matlab.ui.control.NumericEditField
        ResolutionWidthEditFieldLabel   matlab.ui.control.Label
        InitialiseSensorsTab            matlab.ui.container.Tab
        InitialiseGyroscopePanel        matlab.ui.container.Panel
        degsqrthrLabel                  matlab.ui.control.Label
        degsLabel_13                    matlab.ui.control.Label
        DriftRateEditField_2            matlab.ui.control.NumericEditField
        NoiseEditField_4                matlab.ui.control.NumericEditField
        NoiseEditField_4Label           matlab.ui.control.Label
        DriftRateEditField_2Label       matlab.ui.control.Label
        HzLabel_8                       matlab.ui.control.Label
        SampleTimeEditField_6           matlab.ui.control.NumericEditField
        SampleTimeEditField_6Label      matlab.ui.control.Label
        EnableCheckBox_4                matlab.ui.control.CheckBox
        InitialiseMagnetometerPanel     matlab.ui.container.Panel
        SampleTimeEditField_7           matlab.ui.control.NumericEditField
        SampleTimeEditField_7Label      matlab.ui.control.Label
        HzLabel_10                      matlab.ui.control.Label
        EnableCheckBox_6                matlab.ui.control.CheckBox
        nTLabel                         matlab.ui.control.Label
        NoiseEditField_6                matlab.ui.control.NumericEditField
        NoiseEditField_6Label           matlab.ui.control.Label
        InitialiseGPSPanel              matlab.ui.container.Panel
        msqrthrLabel                    matlab.ui.control.Label
        mLabel_9                        matlab.ui.control.Label
        DriftRateEditField              matlab.ui.control.NumericEditField
        NoiseEditField_3                matlab.ui.control.NumericEditField
        NoiseEditField_3Label           matlab.ui.control.Label
        DriftRateEditFieldLabel         matlab.ui.control.Label
        HzLabel_6                       matlab.ui.control.Label
        SampleTimeEditField_5           matlab.ui.control.NumericEditField
        SampleTimeEditField_5Label      matlab.ui.control.Label
        EnableCheckBox_3                matlab.ui.control.CheckBox
        InitialiseStarTrackerPanel      matlab.ui.container.Panel
        arcsecondsLabel                 matlab.ui.control.Label
        NoiseEditField_2                matlab.ui.control.NumericEditField
        NoiseEditField_2Label           matlab.ui.control.Label
        HzLabel_4                       matlab.ui.control.Label
        SampleTimeEditField_4           matlab.ui.control.NumericEditField
        SampleTimeEditField_4Label      matlab.ui.control.Label
        EnableCheckBox_2                matlab.ui.control.CheckBox
        InitialiseCoarseSunSensorPanel  matlab.ui.container.Panel
        SampleTimeEditField_8           matlab.ui.control.NumericEditField
        SampleTimeEditField_8Label      matlab.ui.control.Label
        HzLabel_11                      matlab.ui.control.Label
        EnableCheckBox_5                matlab.ui.control.CheckBox
        errorsensorLabel                matlab.ui.control.Label
        NoiseEditField_5                matlab.ui.control.NumericEditField
        NoiseEditField_5Label           matlab.ui.control.Label
        InitialiseEarthTrackerPanel     matlab.ui.container.Panel
        mLabel_8                        matlab.ui.control.Label
        NoiseEditField                  matlab.ui.control.NumericEditField
        NoiseEditFieldLabel             matlab.ui.control.Label
        HzLabel_9                       matlab.ui.control.Label
        SampleTimeEditField_3           matlab.ui.control.NumericEditField
        SampleTimeEditField_3Label      matlab.ui.control.Label
        EnableCheckBox                  matlab.ui.control.CheckBox
        TuneFilterTab                   matlab.ui.container.Tab
        CopyButton_3                    matlab.ui.control.Button
        InitialiseCovarianceMatrixPanel  matlab.ui.container.Panel
        degsLabel_16                    matlab.ui.control.Label
        omega_zEditField_2              matlab.ui.control.NumericEditField
        omega_zEditField_2Label         matlab.ui.control.Label
        degsLabel_15                    matlab.ui.control.Label
        omega_yEditField_2              matlab.ui.control.NumericEditField
        omega_yEditField_2Label         matlab.ui.control.Label
        degsLabel_14                    matlab.ui.control.Label
        omega_xEditField_2              matlab.ui.control.NumericEditField
        omega_xEditField_2Label         matlab.ui.control.Label
        AngularVelocityLabel_2          matlab.ui.control.Label
        q_zEditField_2                  matlab.ui.control.NumericEditField
        q_zEditField_2Label             matlab.ui.control.Label
        q_yEditField_2                  matlab.ui.control.NumericEditField
        q_yEditField_2Label             matlab.ui.control.Label
        q_xEditField_2                  matlab.ui.control.NumericEditField
        q_xEditField_2Label             matlab.ui.control.Label
        q_sEditField_2                  matlab.ui.control.NumericEditField
        q_sEditField_2Label             matlab.ui.control.Label
        AttitudeLabel_2                 matlab.ui.control.Label
        msLabel_6                       matlab.ui.control.Label
        v_zEditField_2                  matlab.ui.control.NumericEditField
        v_zEditField_2Label             matlab.ui.control.Label
        msLabel_5                       matlab.ui.control.Label
        v_yEditField_2                  matlab.ui.control.NumericEditField
        v_yEditField_2Label             matlab.ui.control.Label
        msLabel_4                       matlab.ui.control.Label
        v_xEditField_2                  matlab.ui.control.NumericEditField
        v_xEditField_2Label             matlab.ui.control.Label
        VelocityLabel_2                 matlab.ui.control.Label
        mLabel_12                       matlab.ui.control.Label
        r_zEditField_2                  matlab.ui.control.NumericEditField
        r_zEditField_2Label             matlab.ui.control.Label
        mLabel_11                       matlab.ui.control.Label
        r_yEditField_2                  matlab.ui.control.NumericEditField
        r_yEditField_2Label             matlab.ui.control.Label
        mLabel_10                       matlab.ui.control.Label
        r_xEditField_2                  matlab.ui.control.NumericEditField
        r_xEditField_2Label             matlab.ui.control.Label
        PositionLabel_2                 matlab.ui.control.Label
        TuneFilterPanel                 matlab.ui.container.Panel
        degsLabel_7                     matlab.ui.control.Label
        omega_zEditField                matlab.ui.control.NumericEditField
        omega_zEditFieldLabel           matlab.ui.control.Label
        degsLabel_6                     matlab.ui.control.Label
        omega_yEditField                matlab.ui.control.NumericEditField
        omega_yEditFieldLabel           matlab.ui.control.Label
        degsLabel_5                     matlab.ui.control.Label
        omega_xEditField                matlab.ui.control.NumericEditField
        omega_xEditFieldLabel           matlab.ui.control.Label
        AngularVelocityLabel            matlab.ui.control.Label
        q_zEditField                    matlab.ui.control.NumericEditField
        q_zEditFieldLabel               matlab.ui.control.Label
        q_yEditField                    matlab.ui.control.NumericEditField
        qyLabel                         matlab.ui.control.Label
        q_xEditField                    matlab.ui.control.NumericEditField
        q_xEditFieldLabel               matlab.ui.control.Label
        q_sEditField                    matlab.ui.control.NumericEditField
        q_sEditFieldLabel               matlab.ui.control.Label
        AttitudeLabel                   matlab.ui.control.Label
        msLabel_3                       matlab.ui.control.Label
        v_zEditField                    matlab.ui.control.NumericEditField
        v_zEditFieldLabel               matlab.ui.control.Label
        msLabel_2                       matlab.ui.control.Label
        v_yEditField                    matlab.ui.control.NumericEditField
        v_yEditFieldLabel               matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
        v_xEditField                    matlab.ui.control.NumericEditField
        v_xEditFieldLabel               matlab.ui.control.Label
        VelocityLabel                   matlab.ui.control.Label
        mLabel_3                        matlab.ui.control.Label
        r_zEditField                    matlab.ui.control.NumericEditField
        r_zEditFieldLabel               matlab.ui.control.Label
        mLabel_2                        matlab.ui.control.Label
        r_yEditField                    matlab.ui.control.NumericEditField
        r_yEditFieldLabel               matlab.ui.control.Label
        mLabel                          matlab.ui.control.Label
        r_xEditField                    matlab.ui.control.NumericEditField
        r_xEditFieldLabel               matlab.ui.control.Label
        PositionLabel                   matlab.ui.control.Label
        RunSimulationTab                matlab.ui.container.Tab
        SaveImagesCheckBox              matlab.ui.control.CheckBox
        StopSimulationButton            matlab.ui.control.Button
        RunSimulationButton             matlab.ui.control.Button
        ViewResultsTab                  matlab.ui.container.Tab
        PlotVisualPanel                 matlab.ui.container.Panel
        Plot3DAttitudeVisualisationButton  matlab.ui.control.Button
        Plot3DPositionVisualisationButton  matlab.ui.control.Button
        PlotImagesPanel                 matlab.ui.container.Panel
        EstimatedVideoButton            matlab.ui.control.Button
        ViewDistortedVideoButton        matlab.ui.control.Button
        ViewFeatureVideoButton          matlab.ui.control.Button
        ViewVideoButton                 matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        H_MAGButton                     matlab.ui.control.Button
        H_CSSButton                     matlab.ui.control.Button
        H_STButton                      matlab.ui.control.Button
        H_GYRButton                     matlab.ui.control.Button
        H_GPSButton                     matlab.ui.control.Button
        H_ETButton                      matlab.ui.control.Button
        K_MAGButton                     matlab.ui.control.Button
        K_CSSButton                     matlab.ui.control.Button
        K_STButton                      matlab.ui.control.Button
        K_GYRButton                     matlab.ui.control.Button
        K_GPSButton                     matlab.ui.control.Button
        K_ETButton                      matlab.ui.control.Button
        PlotSensorsPanel                matlab.ui.container.Panel
        hatz_ETButton                   matlab.ui.control.Button
        y_ETButton                      matlab.ui.control.Button
        z_ETButton                      matlab.ui.control.Button
        y_MAGButton                     matlab.ui.control.Button
        hatz_MAGButton                  matlab.ui.control.Button
        y_CSSButton                     matlab.ui.control.Button
        hatz_CSSButton                  matlab.ui.control.Button
        y_STButton                      matlab.ui.control.Button
        hatz_STButton                   matlab.ui.control.Button
        y_GYRButton                     matlab.ui.control.Button
        hatz_GYRButton                  matlab.ui.control.Button
        y_GPSButton                     matlab.ui.control.Button
        hatz_GPSButton                  matlab.ui.control.Button
        z_MAGButton                     matlab.ui.control.Button
        z_STButton                      matlab.ui.control.Button
        z_GYRButton                     matlab.ui.control.Button
        z_GPSButton                     matlab.ui.control.Button
        z_CSSButton                     matlab.ui.control.Button
        PlotSystemPanel                 matlab.ui.container.Panel
        xButton                         matlab.ui.control.Button
        PButton                         matlab.ui.control.Button
        yButton                         matlab.ui.control.Button
        hatxButton                      matlab.ui.control.Button
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end


    properties (Access = public)
        SimulationRunning = false;
    end 


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            %% Initialise Button
            app.SimulationRunning = true;
            app.StopSimulationButton.Enable = 'on';
            app.RunSimulationButton.Enable = 'off';

            %% Initialise Filter
            % Sample Time
            dt_f = 1/app.DeltatEditField_2.Value;
            assignin('base','dt_f',dt_f);

            % Filter Constants
            Mu_f = app.mu_eEditField_2.Value;
            assignin('base','Mu_f',Mu_f);
            Re_f = app.R_eEditField_2.Value;
            assignin('base','Re_f',Re_f);
            J2_f = app.J_2EditField_2.Value;
            assignin('base','J2_f',J2_f);
            I_f = [app.I_xxEditField_2.Value app.I_yyEditField_2.Value app.I_zzEditField_2.Value].';
            assignin('base','I_f',I_f);
            we_f = app.omega_eEditField_2.Value;
            assignin('base','we_f',we_f);

            % Filter States
            % Position and Velocity
            lat_f = app.LattitudeEditField_2.Value;
            assignin("base","lat_f",lat_f);
            lon_f = app.LongitudeEditField_2.Value;
            assignin("base","lon_f",lon_f);
            alt_f = app.AltitudeEditField_2.Value;
            assignin("base","alt_f",alt_f);
            inc_f = app.OrbitInclinationEditField_2.Value;
            assignin("base","inc_f",inc_f);
            % Rotation
            rotx_f = app.CameraRollEditField_2.Value;
            assignin("base","rotx_f",rotx_f);
            roty_f = app.CameraPitchEditField_2.Value;
            assignin("base","roty_f",roty_f);
            rotz_f = app.CameraYawEditField_2.Value;
            assignin("base","rotz_f",rotz_f);
            % Rotation Rate
            wx_f = app.CameraRollRateEditField_2.Value;
            assignin("base","wx_f",wx_f);
            wy_f = app.CameraPitchRateEditField_2.Value;
            assignin("base","wy_f",wy_f);
            wz_f = app.CameraYawRateEditField_2.Value;
            assignin("base","wz_f",wz_f);

            %% Initialise ET Camera

            % ET Camera
            imgWidth_ET = app.ResolutionWidthEditField_2.Value;
            assignin("base","imgWidth_ET",imgWidth_ET);
            imgHeight_ET = app.ResolutionHeightEditField_2.Value;
            assignin("base","imgHeight_ET",imgHeight_ET);
            focalLength_ET = app.FocalLengthEditField_2.Value;
            assignin("base","focalLength_ET",focalLength_ET);
            pixelSize_ET = app.PixelSizeEditField_2.Value;
            assignin("base","pixelSize_ET",pixelSize_ET);
            alpha_ET = app.AlphaEditField_2.Value;
            assignin("base","alpha_ET",alpha_ET);
            alpha_m = app.AlphaEditField.Value;
            assignin("base","alpha_m",alpha_m);

            % Distortions Values
            k1 = app.k1EditField.Value;
            assignin("base","k1",k1);
            k2 = app.k2EditField.Value;
            assignin("base","k2",k2);
            k3 = app.k3EditField.Value;
            assignin("base","k3",k3);
            p1 = app.p1EditField.Value;
            assignin("base","p1",p1);
            p2 = app.p2EditField.Value;
            assignin("base","p2",p2);
            R = app.REditField.Value;
            assignin("base","R",R);
            G = app.GEditField.Value;
            assignin("base","G",G);
            B = app.BEditField.Value;
            assignin("base","B",B);

            radial_enable = app.RadialCheckBox.Value;
            assignin("base","radial_enable",radial_enable);
            tangential_enable = app.TangentialCheckBox.Value;
            assignin("base","tangential_enable",tangential_enable);
            chromatic_enable = app.ChromaticCheckBox.Value;
            assignin("base","chromatic_enable",chromatic_enable);

            n_f = app.NumberofFeaturesEditField.Value;
            assignin('base','n_f',n_f);

            selected = app.FeatureDetectionMethodButtonGroup.SelectedObject;
            if selected == app.SIFTButton
                FD = 1;
                assignin('base','FD',FD);
            elseif selected == app.SURFButton
                FD = 2;
                assignin('base','FD',FD);
            elseif selected == app.ORBButton
                FD = 3;
                assignin('base','FD',FD);
            end


            %% Initialise Sensors
            % Earth Tracker
            dt_ET = 1/app.SampleTimeEditField_3.Value;
            assignin("base","dt_ET",dt_ET);
            noise_ET = app.NoiseEditField.Value;
            assignin("base","noise_ET",noise_ET);
            enable_ET = app.EnableCheckBox.Value;
            assignin("base","enable_ET",enable_ET);

            % Star Tracker
            dt_ST = 1/app.SampleTimeEditField_4.Value;
            assignin("base","dt_ST",dt_ST);
            noise_ST = app.NoiseEditField_2.Value;
            assignin("base","noise_ST",noise_ST);
            enable_ST = app.EnableCheckBox_2.Value;
            assignin("base","enable_ST",enable_ST);

            % GPS
            dt_GPS = 1/app.SampleTimeEditField_5.Value;
            assignin("base","dt_GPS",dt_GPS);
            noise_GPS = app.NoiseEditField_3.Value;
            assignin("base","noise_GPS",noise_GPS);
            driftRate_GPS = app.DriftRateEditField.Value;
            assignin("base","driftRate_GPS",driftRate_GPS);
            enable_GPS = app.EnableCheckBox_3.Value;
            assignin("base","enable_GPS",enable_GPS);

            % Gyroscope
            dt_GYR = 1/app.SampleTimeEditField_6.Value;
            assignin("base","dt_GYR",dt_GYR);
            noise_GYR = app.NoiseEditField_4.Value;
            assignin("base","noise_GYR",noise_GYR);
            driftRate_GYR = app.DriftRateEditField_2.Value;
            assignin("base","driftRate_GYR",driftRate_GYR);
            enable_GYR = app.EnableCheckBox_4.Value;
            assignin("base","enable_GYR",enable_GYR);

            % CSS
            dt_CSS = 1/app.SampleTimeEditField_8.Value;
            assignin("base","dt_CSS",dt_CSS);
            noise_CSS = app.NoiseEditField_5.Value;
            assignin("base","noise_CSS",noise_CSS);
            enable_CSS = app.EnableCheckBox_5.Value;
            assignin("base","enable_CSS",enable_CSS);

            % Mag
            dt_MAG = 1/app.SampleTimeEditField_7.Value;
            assignin("base","dt_MAG",dt_MAG);
            noise_MAG = app.NoiseEditField_6.Value;
            assignin("base","noise_MAG",noise_MAG);
            enable_MAG = app.EnableCheckBox_6.Value;
            assignin("base","enable_MAG",enable_MAG);


            %% Tune Filter
            % Position
            Qrx = app.r_xEditField.Value;
            assignin("base","Qrx",Qrx);
            Qry = app.r_yEditField.Value;
            assignin("base","Qry",Qry);
            Qrz = app.r_zEditField.Value;
            assignin("base","Qrz",Qrz);

            % Velocity
            Qvx = app.v_xEditField.Value;
            assignin("base","Qvx",Qvx);
            Qvy = app.v_yEditField.Value;
            assignin("base","Qvy",Qvy);
            Qvz = app.v_zEditField.Value;
            assignin("base","Qvz",Qvz);

            % Attitude
            Qqs = app.q_sEditField.Value;
            assignin("base","Qqs",Qqs);
            Qqx = app.q_xEditField.Value;
            assignin("base","Qqx",Qqx);
            Qqy = app.q_yEditField.Value;
            assignin("base","Qqy",Qqy);
            Qqz = app.q_zEditField.Value;
            assignin("base","Qqz",Qqz);

            % Anglular Velocity
            Qwx = app.omega_xEditField.Value;
            assignin("base","Qwx",Qwx);
            Qwy = app.omega_yEditField.Value;
            assignin("base","Qwy",Qwy);
            Qwz = app.omega_zEditField.Value;
            assignin("base","Qwz",Qwz);

            %% Intialise Covarariance Matrix
            % Position
            Prx = app.r_xEditField_2.Value;
            assignin("base","Prx",Prx);
            Pry = app.r_yEditField_2.Value;
            assignin("base","Pry",Pry);
            Prz = app.r_zEditField_2.Value;
            assignin("base","Prz",Prz);

            % Velocity
            Pvx = app.v_xEditField_2.Value;
            assignin("base","Pvx",Pvx);
            Pvy = app.v_yEditField_2.Value;
            assignin("base","Pvy",Pvy);
            Pvz = app.v_zEditField_2.Value;
            assignin("base","Pvz",Pvz);

            % Attitude
            Pqs = app.q_sEditField_2.Value;
            assignin("base","Pqs",Pqs);
            Pqx = app.q_xEditField_2.Value;
            assignin("base","Pqx",Pqx);
            Pqy = app.q_yEditField_2.Value;
            assignin("base","Pqy",Pqy);
            Pqz = app.q_zEditField_2.Value;
            assignin("base","Pqz",Pqz);

            % Anglular Velocity
            Pwx = app.omega_xEditField_2.Value;
            assignin("base","Pwx",Pwx);
            Pwy = app.omega_yEditField_2.Value;
            assignin("base","Pwy",Pwy);
            Pwz = app.omega_zEditField_2.Value;
            assignin("base","Pwz",Pwz);

            app.SimulationRunning = true;
            app.StopSimulationButton.Enable = 'on';
            app.RunSimulationButton.Enable = 'off';

            % Pass the app handle to System
            enable_SaveImages = app.SaveImagesCheckBox.Value;
            assignin("base","enable_SaveImages",enable_SaveImages);
            System(app);
        end

        % Button pushed function: StopSimulationButton
        function StopSimulationButtonPushed(app, event)
            app.SimulationRunning = false;
            app.StopSimulationButton.Enable = 'off';
            app.RunSimulationButton.Enable = 'on';
            fprintf("Stop requested - will stop at next checkpoint\n");
        end

        % Button pushed function: xButton
        function xButtonPushed(app, event)
            % Access the state data from base workspace
            x_true = evalin('base', 'x_true');  % Your state matrix
            x_EKF = evalin('base', 'x_EKF');  % Your state matrix
            dt_p = evalin('base', 'dt_p');  % Your time step

            % Call the PlotState function
            PlotState(x_true, x_EKF, dt_p);
        end

        % Button pushed function: hatxButton
        function hatxButtonPushed(app, event)
            % Access the state data from base workspace
            x_EKF = evalin('base', 'x_EKF');  % Your state matrix
            dt_f = evalin('base', 'dt_f');  % Your time step

            % Call the PlotState function
            PlotEstimatedState(x_EKF, dt_f);
        end

        % Button pushed function: ViewVideoButton
        function ViewVideoButtonPushed(app, event)
            dt_ET = evalin('base', 'dt_ET');  % Your time step
            MakeSatelliteVideo(dt_ET);
            videoPath = 'C:\Users\Niel\Desktop\1. Masters\2. System\6. Plots\Video\satellite_video.mp4';
            winopen(videoPath);
        end

        % Button pushed function: z_CSSButton
        function z_CSSButtonPushed(app, event)
            x_true = evalin('base', 'x_true');  % Your state matrix
            dt_p = evalin('base', 'dt_p');  % Your time step
            z_CSS = evalin('base', 'z_CSS');
            
            PlotCSS(x_true,dt_p,z_CSS);
        end

        % Button pushed function: z_GPSButton
        function z_GPSButtonPushed(app, event)
            x_true = evalin('base', 'x_true');  % Your state matrix
            dt_p = evalin('base', 'dt_p');  % Your time step
            z_GPS = evalin('base', 'z_GPS');
            
            PlotGPS(x_true,dt_p,z_GPS);
        end

        % Button pushed function: z_GYRButton
        function z_GYRButtonPushed(app, event)
             x_true = evalin('base', 'x_true');  % Your state matrix
            dt_p = evalin('base', 'dt_p');  % Your time step
            z_GYR = evalin('base', 'z_GYR');
            
            PlotGyro(x_true,dt_p,z_GYR);
        end

        % Button pushed function: z_STButton
        function z_STButtonPushed(app, event)
            x_true = evalin('base', 'x_true');  % Your state matrix
            dt_p = evalin('base', 'dt_p');  % Your time step
            z_ST = evalin('base', 'z_ST');
            
            PlotST(z_ST, dt_p);
        end

        % Button pushed function: yButton
        function yButtonPushed(app, event)
            x_true = evalin('base', 'x_true');  % Your state matrix
            x_EKF = evalin('base', 'x_EKF');  % Your state matrix
            dt_p = evalin('base', 'dt_p');  % Your time step

            PlotStateError(x_true,x_EKF,dt_p);
        end

        % Button pushed function: PButton
        function PButtonPushed(app, event)
            x_true = evalin('base', 'x_true');  % Your state matrix
            x_EKF = evalin('base', 'x_EKF');  % Your state matrix
            P_EKF  = evalin('base', 'P_EKF');  % Your state matrix

            PlotCovariance(x_true,x_EKF,P_EKF)
        end

        % Button pushed function: ViewFeatureVideoButton
        function ViewFeatureVideoButtonPushed(app, event)
            dt_ET = evalin('base', 'dt_ET');  % Your time step
            MakeFeatureVideo(dt_ET);
            videoPath = 'C:\Users\Niel\Desktop\1. Masters\2. System\6. Plots\Video\feature_video.mp4';
            winopen(videoPath);
        end

        % Button pushed function: Plot3DPositionVisualisationButton
        function Plot3DPositionVisualisationButtonPushed(app, event)
            x_true = evalin('base', 'x_true');  % Your state matrix
            x_EKF = evalin('base', 'x_EKF');  % Your state matrix

            Plot3D(x_true,x_EKF);
        end

        % Callback function: not associated with a component
        function PlotMeasurementButtonPushed(app, event)
            z_ET = evalin('base', 'z_ET');  % Your time step
            n_f = evalin('base', 'n_f');  % Your time step
            dt_p = evalin('base', 'dt_p');  % Your time step

            PlotMeasurement(z_ET,n_f,dt_p);
        end

        % Callback function: not associated with a component
        function PlotEstimatedMeasurementButtonPushed(app, event)
            zhat_ET = evalin('base', 'zhat_ET');  % Your time step
            n_f = evalin('base', 'n_f');  % Your time step
            dt_p = evalin('base', 'dt_p');  % Your time step

            PlotEstimatedMeasurement(zhat_ET,n_f,dt_p);
        end

        % Callback function: not associated with a component
        function PlotMeasurementErrorButtonPushed(app, event)
            z_ET = evalin('base', 'z_ET');  % Your time step
            zhat_ET = evalin('base', 'zhat_ET');  % Your time step
            n_f = evalin('base', 'n_f');  % Your time step
            dt_p = evalin('base', 'dt_p');  % Your time step

            PlotMeasurementError(z_ET,zhat_ET,n_f,dt_p);
        end

        % Button pushed function: z_MAGButton
        function z_MAGButtonPushed(app, event)
            x_true = evalin('base', 'x_true');  % Your state matrix
            dt_p = evalin('base', 'dt_p');  % Your time step
            z_MAG = evalin('base', 'z_MAG');

            PlotMag(x_true,dt_p,z_MAG);
        end

        % Button pushed function: LoadSimulatorButton
        function LoadSimulatorButtonPushed(app, event)
           %% Initilise Simulator
            warning('off');
            CleanFolders;
            evalin('base', 'clear all');
            evalin('base', 'clc');
            close all;
            %---
            %% Load dataset
            % DB now contains top-level variables: 'poses', 'images', 'metadata'
            dataset = [];  % Initialize
            DB = app.DatasetEditField.Value + ".mat";
            dataset = load(DB);
            assignin("base","dataset",dataset)
            %---
 
            % Load Values (now using dataset.poses and dataset.images directly)
            x_true = dataset.poses;
            assignin("base","x_true",x_true);
            satelliteImage = dataset.images;
            assignin("base","satelliteImage",satelliteImage);
            % we_p and all other metadata fields are now under dataset.metadata
            we_p = dataset.metadata.physics.we;
            assignin("base","we_p",we_p);
            time = dataset.time;
            assignin("base","time",time);

            %% Simualtor
            % Accessing simulationTime via metadata
            app.SimulationTimeEditField.Value = dataset.metadata.simulationTime;
            app.SimulationTimeEditField.Enable = 'off';
            st = dataset.metadata.simulationTime;
            assignin("base","st",st);

            %% Initialise Plant
            % Sample Time
            % Accessing sampleTime via metadata
            app.DeltatEditField.Value = 1/dataset.metadata.sampleTime;
            app.DeltatEditField.Enable = 'off';
            dt_p = dataset.metadata.sampleTime;
            assignin("base","dt_p",dt_p);

            % Plant Constants
            % Accessing all physics constants via metadata
            app.mu_eEditField.Value = dataset.metadata.physics.Mu;
            app.mu_eEditField.Enable = 'off';
            Mu_p = dataset.metadata.physics.Mu;
            assignin("base","Mu_p",Mu_p);
            app.R_eEditField.Value = dataset.metadata.physics.Re;
            app.R_eEditField.Enable = 'off';
            app.J_2EditField.Value = dataset.metadata.physics.J2;
            app.J_2EditField.Enable = 'off';
            app.I_xxEditField.Value = dataset.metadata.physics.I(1,1);
            app.I_xxEditField.Enable = 'off';
            app.I_yyEditField.Value = dataset.metadata.physics.I(2,1);
            app.I_yyEditField.Enable = 'off';
            app.I_zzEditField.Value = dataset.metadata.physics.I(3,1);
            app.I_zzEditField.Enable = 'off';
            app.omega_eEditField.Value = dataset.metadata.physics.we;
            app.omega_eEditField.Enable = 'off';

            % Plant States
            % Position and Velocity (Accessed via metadata.initial)
            app.LattitudeEditField.Value = dataset.metadata.initial.lat;
            app.LattitudeEditField.Enable = 'off';
            app.LongitudeEditField.Value = dataset.metadata.initial.lon;
            app.LongitudeEditField.Enable = 'off';
            app.AltitudeEditField.Value = dataset.metadata.initial.alt;
            app.AltitudeEditField.Enable = 'off';
            app.OrbitInclinationEditField.Value = dataset.metadata.initial.inc;
            app.OrbitInclinationEditField.Enable = 'off';

            % Rotation (Accessed via metadata.initial)
            app.CameraRollEditField.Value = dataset.metadata.initial.rotx;
            app.CameraRollEditField.Enable = 'off';
            app.CameraPitchEditField.Value = dataset.metadata.initial.roty;
            app.CameraPitchEditField.Enable = 'off';
            app.CameraYawEditField.Value = dataset.metadata.initial.rotz; % Fixed: changed roty to rotz for Yaw
            app.CameraYawEditField.Enable = 'off';

            % Rotation Rate (Accessed via metadata.initial)
            app.CameraRollRateEditField.Value = dataset.metadata.initial.wx;
            app.CameraRollRateEditField.Enable = 'off';
            app.CameraPitchRateEditField.Value = dataset.metadata.initial.wy;
            app.CameraPitchRateEditField.Enable = 'off';
            app.CameraYawRateEditField.Value = dataset.metadata.initial.wz; % Fixed: changed wx to wz for Yaw Rate
            app.CameraYawRateEditField.Enable = 'off';

            %% Initialise Camera
            % Satellite Camera (Accessed via metadata.camera)
            app.ResolutionWidthEditField.Value = dataset.metadata.camera.imgWidth;
            Ix = dataset.metadata.camera.imgWidth;
            assignin("base","Ix",Ix);
            app.ResolutionWidthEditField.Enable = 'off';
            app.ResolutionHeightEditField.Value = dataset.metadata.camera.imgHeight;
            Iy = dataset.metadata.camera.imgHeight;
            assignin("base","Iy",Iy);
            app.ResolutionHeightEditField.Enable = 'off';
            app.FocalLengthEditField.Value = dataset.metadata.camera.focalLength;
            focalLength_cam = dataset.metadata.camera.focalLength;
            assignin("base","focalLength_cam",focalLength_cam);
            app.FocalLengthEditField.Enable = 'off';
            app.PixelSizeEditField.Value = dataset.metadata.camera.pixelSize;
            pixelSize_cam = dataset.metadata.camera.pixelSize;
            assignin("base","pixelSize_cam",pixelSize_cam);
            app.PixelSizeEditField.Enable = 'off';
            
        end

        % Button pushed function: CopyButton
        function CopyButtonPushed(app, event)
            % Constants
            app.DeltatEditField_2.Value = app.DeltatEditField.Value;
            app.mu_eEditField_2.Value = app.mu_eEditField.Value;
            app.R_eEditField_2.Value = app.R_eEditField.Value;
            app.omega_eEditField_2.Value = app.omega_eEditField.Value;
            app.J_2EditField_2.Value = app.J_2EditField.Value;
            app.I_xxEditField_2.Value = app.I_xxEditField.Value;
            app.I_yyEditField_2.Value = app.I_yyEditField.Value;
            app.I_zzEditField_2.Value = app.I_zzEditField.Value;
            %---

            % Initial Paramters
            app.LattitudeEditField_2.Value  = app.LattitudeEditField.Value;
            app.LongitudeEditField_2.Value  = app.LongitudeEditField.Value;
            app.AltitudeEditField_2.Value   = app.AltitudeEditField.Value;
            app.OrbitInclinationEditField_2.Value = app.OrbitInclinationEditField.Value;
            %---

            % Initial Rotation
            app.CameraRollEditField_2.Value = app.CameraRollEditField.Value;
            app.CameraPitchEditField_2.Value = app.CameraPitchEditField.Value;
            app.CameraYawEditField_2.Value = app.CameraYawEditField.Value;
            %---

            % Initial Angular Velocity
            app.CameraRollRateEditField_2.Value = app.CameraRollRateEditField.Value;
            app.CameraPitchRateEditField_2.Value = app.CameraPitchRateEditField.Value;
            app.CameraYawRateEditField_2.Value = app.CameraYawRateEditField.Value;

        end

        % Button pushed function: CopyButton_2
        function CopyButton_2Pushed(app, event)
            app.ResolutionWidthEditField_2.Value = app.ResolutionWidthEditField.Value;
            app.ResolutionHeightEditField_2.Value = app.ResolutionHeightEditField.Value;
            app.FocalLengthEditField_2.Value = app.FocalLengthEditField.Value;
            app.PixelSizeEditField_2.Value = app.PixelSizeEditField.Value;
            app.AlphaEditField.Value = app.AlphaEditField_2.Value;
        end

        % Button pushed function: CopyButton_3
        function CopyButton_3Pushed(app, event)
            app.r_xEditField.Value = app.r_xEditField_2.Value;
            app.r_yEditField.Value = app.r_yEditField_2.Value;
            app.r_zEditField.Value = app.r_zEditField_2.Value;
            app.v_xEditField.Value = app.v_xEditField_2.Value;
            app.v_yEditField.Value = app.v_yEditField_2.Value;
            app.v_zEditField.Value = app.v_zEditField_2.Value;
            app.q_sEditField.Value = app.q_sEditField_2.Value;
            app.q_xEditField.Value = app.q_xEditField_2.Value;
            app.q_yEditField.Value = app.q_yEditField_2.Value;
            app.q_zEditField.Value = app.q_zEditField_2.Value;
            app.omega_xEditField.Value = app.omega_xEditField_2.Value;
            app.omega_yEditField.Value = app.omega_xEditField_2.Value;
            app.omega_zEditField.Value = app.omega_xEditField_2.Value;
        end

        % Button pushed function: ViewDistortedVideoButton
        function ViewDistortedVideoButtonPushed(app, event)
            dt_ET = evalin('base', 'dt_ET');  % Your time step
            MakeDistortedVideo(dt_ET);
            videoPath = 'C:\Users\Niel\Desktop\1. Masters\2. System\6. Plots\Video\distorted_video.mp4';
            winopen(videoPath);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LoadDatasetTab = uitab(app.TabGroup);
            app.LoadDatasetTab.Title = 'Load Dataset';
            app.LoadSimulatorButton = uibutton(app.LoadDatasetTab, 'push');
            app.LoadSimulatorButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSimulatorButtonPushed, true);
            app.LoadSimulatorButton.Text = 'Load Simulator';
            app.DatasetEditFieldLabel = uilabel(app.LoadDatasetTab);
            app.DatasetEditFieldLabel.Text = 'Dataset';
            app.DatasetEditField = uieditfield(app.LoadDatasetTab, 'text');
            app.InitialiseSimulationTab = uitab(app.TabGroup);
            app.InitialiseSimulationTab.Title = 'Initialise Simulation';
            app.SimulationParametersPanel = uipanel(app.InitialiseSimulationTab);
            app.SimulationParametersPanel.Title = 'Simulation Parameters';
            app.SimulationTimeEditFieldLabel = uilabel(app.SimulationParametersPanel);
            app.SimulationTimeEditFieldLabel.Text = 'Simulation Time';
            app.SimulationTimeEditField = uieditfield(app.SimulationParametersPanel, 'numeric');
            app.sLabel_2 = uilabel(app.SimulationParametersPanel);
            app.sLabel_2.Interpreter = 'latex';
            app.sLabel_2.Text = '$s$';
            app.InitialiseSatellitePanel = uipanel(app.InitialiseSimulationTab);
            app.InitialiseSatellitePanel.Title = 'Initialise Satellite';
            app.ConstantsPanel = uipanel(app.InitialiseSatellitePanel);
            app.ConstantsPanel.Title = 'Constants';
            app.DeltatEditFieldLabel = uilabel(app.ConstantsPanel);
            app.DeltatEditFieldLabel.Interpreter = 'latex';
            app.DeltatEditFieldLabel.Text = '$\Delta t $';
            app.DeltatEditField = uieditfield(app.ConstantsPanel, 'numeric');
            app.HzLabel = uilabel(app.ConstantsPanel);
            app.HzLabel.Interpreter = 'latex';
            app.HzLabel.Text = '$Hz$';
            app.mu_eEditFieldLabel = uilabel(app.ConstantsPanel);
            app.mu_eEditFieldLabel.Interpreter = 'latex';
            app.mu_eEditFieldLabel.Text = '$\mu_e$';
            app.mu_eEditField = uieditfield(app.ConstantsPanel, 'numeric');
            app.mu_eEditField.ValueDisplayFormat = '%.3e';
            app.km3s2Label = uilabel(app.ConstantsPanel);
            app.km3s2Label.Interpreter = 'latex';
            app.km3s2Label.Text = '$km^3/s^2$';
            app.R_eEditFieldLabel = uilabel(app.ConstantsPanel);
            app.R_eEditFieldLabel.Interpreter = 'latex';
            app.R_eEditFieldLabel.Text = '$R_e$';
            app.R_eEditField = uieditfield(app.ConstantsPanel, 'numeric');
            app.R_eEditField.ValueDisplayFormat = '%.3e';
            app.kmLabel = uilabel(app.ConstantsPanel);
            app.kmLabel.Interpreter = 'latex';
            app.kmLabel.Text = '$km$';
            app.omega_eEditFieldLabel = uilabel(app.ConstantsPanel);
            app.omega_eEditFieldLabel.Interpreter = 'latex';
            app.omega_eEditFieldLabel.Text = '$\omega_e$';
            app.omega_eEditField = uieditfield(app.ConstantsPanel, 'numeric');
            app.omega_eEditField.ValueDisplayFormat = '%.3e';
            app.degsLabel = uilabel(app.ConstantsPanel);
            app.degsLabel.Interpreter = 'latex';
            app.degsLabel.Text = '$deg/s$';
            app.J_2EditFieldLabel = uilabel(app.ConstantsPanel);
            app.J_2EditFieldLabel.Interpreter = 'latex';
            app.J_2EditFieldLabel.Text = '$J_2$';
            app.J_2EditField = uieditfield(app.ConstantsPanel, 'numeric');
            app.J_2EditField.ValueDisplayFormat = '%.3e';
            app.I_xxEditFieldLabel = uilabel(app.ConstantsPanel);
            app.I_xxEditFieldLabel.Interpreter = 'latex';
            app.I_xxEditFieldLabel.Text = '$I_{xx}$';
            app.I_xxEditField = uieditfield(app.ConstantsPanel, 'numeric');
            app.I_xxEditField.ValueDisplayFormat = '%.3e';
            app.kgtimesm2Label = uilabel(app.ConstantsPanel);
            app.kgtimesm2Label.Interpreter = 'latex';
            app.kgtimesm2Label.Text = '$kg\times m^2$';
            app.I_yyEditFieldLabel = uilabel(app.ConstantsPanel);
            app.I_yyEditFieldLabel.Interpreter = 'latex';
            app.I_yyEditFieldLabel.Text = '$I_{yy}$';
            app.I_zzEditFieldLabel = uilabel(app.ConstantsPanel);
            app.I_zzEditFieldLabel.Interpreter = 'latex';
            app.I_zzEditFieldLabel.Text = '$I_{zz}$';
            app.I_yyEditField = uieditfield(app.ConstantsPanel, 'numeric');
            app.I_yyEditField.ValueDisplayFormat = '%.3e';
            app.I_zzEditField = uieditfield(app.ConstantsPanel, 'numeric');
            app.I_zzEditField.ValueDisplayFormat = '%.3e';
            app.kgtimesm2Label_2 = uilabel(app.ConstantsPanel);
            app.kgtimesm2Label_2.Interpreter = 'latex';
            app.kgtimesm2Label_2.Text = '$kg\times m^2$';
            app.kgtimesm2Label_3 = uilabel(app.ConstantsPanel);
            app.kgtimesm2Label_3.Interpreter = 'latex';
            app.kgtimesm2Label_3.Text = '$kg\times m^2$';
            app.InitialStatesPanel = uipanel(app.InitialiseSatellitePanel);
            app.InitialStatesPanel.Title = 'Initial States';
            app.InitialPositionandVelocityLabel = uilabel(app.InitialStatesPanel);
            app.InitialPositionandVelocityLabel.Text = 'Initial Position and Velocity';
            app.LattitudeEditFieldLabel = uilabel(app.InitialStatesPanel);
            app.LattitudeEditFieldLabel.Text = 'Lattitude';
            app.LattitudeEditField = uieditfield(app.InitialStatesPanel, 'numeric');
            app.degLabel = uilabel(app.InitialStatesPanel);
            app.degLabel.Interpreter = 'latex';
            app.degLabel.Text = '$deg$';
            app.LongitudeEditFieldLabel = uilabel(app.InitialStatesPanel);
            app.LongitudeEditFieldLabel.Text = 'Longitude';
            app.LongitudeEditField = uieditfield(app.InitialStatesPanel, 'numeric');
            app.degLabel_2 = uilabel(app.InitialStatesPanel);
            app.degLabel_2.Interpreter = 'latex';
            app.degLabel_2.Text = '$deg$';
            app.AltitudeEditFieldLabel = uilabel(app.InitialStatesPanel);
            app.AltitudeEditFieldLabel.Text = 'Altitude';
            app.AltitudeEditField = uieditfield(app.InitialStatesPanel, 'numeric');
            app.kmLabel_2 = uilabel(app.InitialStatesPanel);
            app.kmLabel_2.Interpreter = 'latex';
            app.kmLabel_2.Text = '$km$';
            app.OrbitInclinationEditFieldLabel = uilabel(app.InitialStatesPanel);
            app.OrbitInclinationEditFieldLabel.Text = 'Orbit Inclination';
            app.OrbitInclinationEditField = uieditfield(app.InitialStatesPanel, 'numeric');
            app.degLabel_6 = uilabel(app.InitialStatesPanel);
            app.degLabel_6.Interpreter = 'latex';
            app.degLabel_6.Text = '$deg$';
            app.InitialRotationLabel = uilabel(app.InitialStatesPanel);
            app.InitialRotationLabel.Text = 'Initial Rotation';
            app.CameraRollEditFieldLabel = uilabel(app.InitialStatesPanel);
            app.CameraRollEditFieldLabel.Text = 'Camera Roll';
            app.CameraRollEditField = uieditfield(app.InitialStatesPanel, 'numeric');
            app.degLabel_3 = uilabel(app.InitialStatesPanel);
            app.degLabel_3.Interpreter = 'latex';
            app.degLabel_3.Text = '$deg$';
            app.CameraPitchEditFieldLabel = uilabel(app.InitialStatesPanel);
            app.CameraPitchEditFieldLabel.Text = 'Camera Pitch';
            app.CameraPitchEditField = uieditfield(app.InitialStatesPanel, 'numeric');
            app.degLabel_4 = uilabel(app.InitialStatesPanel);
            app.degLabel_4.Interpreter = 'latex';
            app.degLabel_4.Text = '$deg$';
            app.CameraYawEditFieldLabel = uilabel(app.InitialStatesPanel);
            app.CameraYawEditFieldLabel.Text = 'Camera Yaw';
            app.CameraYawEditField = uieditfield(app.InitialStatesPanel, 'numeric');
            app.degLabel_5 = uilabel(app.InitialStatesPanel);
            app.degLabel_5.Interpreter = 'latex';
            app.degLabel_5.Text = '$deg$';
            app.InitialAngularVelocityLabel = uilabel(app.InitialStatesPanel);
            app.InitialAngularVelocityLabel.Text = 'Initial Angular Velocity';
            app.CameraRollRateEditFieldLabel = uilabel(app.InitialStatesPanel);
            app.CameraRollRateEditFieldLabel.Text = 'Camera Roll Rate';
            app.CameraRollRateEditField = uieditfield(app.InitialStatesPanel, 'numeric');
            app.degsLabel_2 = uilabel(app.InitialStatesPanel);
            app.degsLabel_2.Interpreter = 'latex';
            app.degsLabel_2.Text = '$deg/s$';
            app.CameraPitchRateEditFieldLabel = uilabel(app.InitialStatesPanel);
            app.CameraPitchRateEditFieldLabel.Text = 'Camera Pitch Rate';
            app.CameraPitchRateEditField = uieditfield(app.InitialStatesPanel, 'numeric');
            app.degsLabel_3 = uilabel(app.InitialStatesPanel);
            app.degsLabel_3.Interpreter = 'latex';
            app.degsLabel_3.Text = '$deg/s$';
            app.CameraYawRateEditFieldLabel = uilabel(app.InitialStatesPanel);
            app.CameraYawRateEditFieldLabel.Text = 'Camera Yaw Rate';
            app.CameraYawRateEditField = uieditfield(app.InitialStatesPanel, 'numeric');
            app.degsLabel_4 = uilabel(app.InitialStatesPanel);
            app.degsLabel_4.Interpreter = 'latex';
            app.degsLabel_4.Text = '$deg/s$';
            app.InitialiseFilterPanel = uipanel(app.InitialiseSimulationTab);
            app.InitialiseFilterPanel.Title = 'Initialise Filter';
            app.ConstantsPanel_2 = uipanel(app.InitialiseFilterPanel);
            app.ConstantsPanel_2.Title = 'Constants';
            app.DeltatEditField_2Label = uilabel(app.ConstantsPanel_2);
            app.DeltatEditField_2Label.Interpreter = 'latex';
            app.DeltatEditField_2Label.Text = '$\Delta t$';
            app.DeltatEditField_2 = uieditfield(app.ConstantsPanel_2, 'numeric');
            app.HzLabel_2 = uilabel(app.ConstantsPanel_2);
            app.HzLabel_2.Interpreter = 'latex';
            app.HzLabel_2.Text = '$Hz$';
            app.mu_eEditField_2Label = uilabel(app.ConstantsPanel_2);
            app.mu_eEditField_2Label.Interpreter = 'latex';
            app.mu_eEditField_2Label.Text = '$\mu_e$';
            app.mu_eEditField_2 = uieditfield(app.ConstantsPanel_2, 'numeric');
            app.mu_eEditField_2.ValueDisplayFormat = '%.3e';
            app.km3s2Label_2 = uilabel(app.ConstantsPanel_2);
            app.km3s2Label_2.Interpreter = 'latex';
            app.km3s2Label_2.Text = '$km^3/s^2$';
            app.R_eEditField_2Label = uilabel(app.ConstantsPanel_2);
            app.R_eEditField_2Label.Interpreter = 'latex';
            app.R_eEditField_2Label.Text = '$R_e$';
            app.R_eEditField_2 = uieditfield(app.ConstantsPanel_2, 'numeric');
            app.R_eEditField_2.ValueDisplayFormat = '%.3e';
            app.kmLabel_3 = uilabel(app.ConstantsPanel_2);
            app.kmLabel_3.Interpreter = 'latex';
            app.kmLabel_3.Text = '$km$';
            app.omega_eEditField_2Label = uilabel(app.ConstantsPanel_2);
            app.omega_eEditField_2Label.Interpreter = 'latex';
            app.omega_eEditField_2Label.Text = '$\omega_e$';
            app.J_2EditField_2Label = uilabel(app.ConstantsPanel_2);
            app.J_2EditField_2Label.Interpreter = 'latex';
            app.J_2EditField_2Label.Text = '$J_2$';
            app.omega_eEditField_2 = uieditfield(app.ConstantsPanel_2, 'numeric');
            app.omega_eEditField_2.ValueDisplayFormat = '%.3e';
            app.J_2EditField_2 = uieditfield(app.ConstantsPanel_2, 'numeric');
            app.J_2EditField_2.ValueDisplayFormat = '%.3e';
            app.degsLabel_8 = uilabel(app.ConstantsPanel_2);
            app.degsLabel_8.Interpreter = 'latex';
            app.degsLabel_8.Text = '$deg/s$';
            app.I_xxEditField_2Label = uilabel(app.ConstantsPanel_2);
            app.I_xxEditField_2Label.Interpreter = 'latex';
            app.I_xxEditField_2Label.Text = '$I_{xx}$';
            app.I_xxEditField_2 = uieditfield(app.ConstantsPanel_2, 'numeric');
            app.I_xxEditField_2.ValueDisplayFormat = '%.3e';
            app.kgtimesm2Label_4 = uilabel(app.ConstantsPanel_2);
            app.kgtimesm2Label_4.Interpreter = 'latex';
            app.kgtimesm2Label_4.Text = '$kg\times m^2$';
            app.I_yyEditField_2Label = uilabel(app.ConstantsPanel_2);
            app.I_yyEditField_2Label.Interpreter = 'latex';
            app.I_yyEditField_2Label.Text = '$I_{yy}$';
            app.I_yyEditField_2 = uieditfield(app.ConstantsPanel_2, 'numeric');
            app.I_yyEditField_2.ValueDisplayFormat = '%.3e';
            app.kgtimesm2Label_5 = uilabel(app.ConstantsPanel_2);
            app.kgtimesm2Label_5.Interpreter = 'latex';
            app.kgtimesm2Label_5.Text = '$kg\times m^2$';
            app.I_zzLabel = uilabel(app.ConstantsPanel_2);
            app.I_zzLabel.Interpreter = 'latex';
            app.I_zzLabel.Text = '$I_{zz}$';
            app.I_zzEditField_2 = uieditfield(app.ConstantsPanel_2, 'numeric');
            app.I_zzEditField_2.ValueDisplayFormat = '%.3e';
            app.kgtimesm2Label_6 = uilabel(app.ConstantsPanel_2);
            app.kgtimesm2Label_6.Interpreter = 'latex';
            app.kgtimesm2Label_6.Text = '$kg\times m^2$';
            app.InitialStatesPanel_2 = uipanel(app.InitialiseFilterPanel);
            app.InitialStatesPanel_2.Title = 'Initial States';
            app.InitialPositionandVelocityLabel_2 = uilabel(app.InitialStatesPanel_2);
            app.InitialPositionandVelocityLabel_2.Text = 'Initial Position and Velocity';
            app.LattitudeEditField_2Label = uilabel(app.InitialStatesPanel_2);
            app.LattitudeEditField_2Label.Text = 'Lattitude';
            app.LattitudeEditField_2 = uieditfield(app.InitialStatesPanel_2, 'numeric');
            app.degLabel_7 = uilabel(app.InitialStatesPanel_2);
            app.degLabel_7.Interpreter = 'latex';
            app.degLabel_7.Text = '$deg$';
            app.LongitudeEditField_2Label = uilabel(app.InitialStatesPanel_2);
            app.LongitudeEditField_2Label.Text = 'Longitude';
            app.LongitudeEditField_2 = uieditfield(app.InitialStatesPanel_2, 'numeric');
            app.degLabel_8 = uilabel(app.InitialStatesPanel_2);
            app.degLabel_8.Interpreter = 'latex';
            app.degLabel_8.Text = '$deg$';
            app.AltitudeEditField_2Label = uilabel(app.InitialStatesPanel_2);
            app.AltitudeEditField_2Label.Text = 'Altitude';
            app.AltitudeEditField_2 = uieditfield(app.InitialStatesPanel_2, 'numeric');
            app.kmLabel_4 = uilabel(app.InitialStatesPanel_2);
            app.kmLabel_4.Interpreter = 'latex';
            app.kmLabel_4.Text = '$km$';
            app.OrbitInclinationEditField_2Label = uilabel(app.InitialStatesPanel_2);
            app.OrbitInclinationEditField_2Label.Text = 'Orbit Inclination';
            app.OrbitInclinationEditField_2 = uieditfield(app.InitialStatesPanel_2, 'numeric');
            app.degLabel_12 = uilabel(app.InitialStatesPanel_2);
            app.degLabel_12.Interpreter = 'latex';
            app.degLabel_12.Text = '$deg$';
            app.InitialRotationLabel_2 = uilabel(app.InitialStatesPanel_2);
            app.InitialRotationLabel_2.Text = 'Initial Rotation';
            app.CameraRollEditField_2Label = uilabel(app.InitialStatesPanel_2);
            app.CameraRollEditField_2Label.Text = 'Camera Roll';
            app.CameraRollEditField_2 = uieditfield(app.InitialStatesPanel_2, 'numeric');
            app.degLabel_9 = uilabel(app.InitialStatesPanel_2);
            app.degLabel_9.Interpreter = 'latex';
            app.degLabel_9.Text = '$deg$';
            app.CameraPitchEditField_2Label = uilabel(app.InitialStatesPanel_2);
            app.CameraPitchEditField_2Label.Text = 'Camera Pitch';
            app.CameraPitchEditField_2 = uieditfield(app.InitialStatesPanel_2, 'numeric');
            app.degLabel_10 = uilabel(app.InitialStatesPanel_2);
            app.degLabel_10.Interpreter = 'latex';
            app.degLabel_10.Text = '$deg$';
            app.CameraYawEditField_2Label = uilabel(app.InitialStatesPanel_2);
            app.CameraYawEditField_2Label.Text = 'Camera Yaw';
            app.CameraYawEditField_2 = uieditfield(app.InitialStatesPanel_2, 'numeric');
            app.degLabel_11 = uilabel(app.InitialStatesPanel_2);
            app.degLabel_11.Interpreter = 'latex';
            app.degLabel_11.Text = '$deg$';
            app.InitialAngularVelocityLabel_2 = uilabel(app.InitialStatesPanel_2);
            app.InitialAngularVelocityLabel_2.Text = 'Initial Angular Velocity';
            app.CameraRollRateEditField_2Label = uilabel(app.InitialStatesPanel_2);
            app.CameraRollRateEditField_2Label.Text = 'Camera Roll Rate';
            app.CameraRollRateEditField_2 = uieditfield(app.InitialStatesPanel_2, 'numeric');
            app.degsLabel_9 = uilabel(app.InitialStatesPanel_2);
            app.degsLabel_9.Interpreter = 'latex';
            app.degsLabel_9.Text = '$deg/s$';
            app.CameraPitchRateEditField_2Label = uilabel(app.InitialStatesPanel_2);
            app.CameraPitchRateEditField_2Label.Text = 'Camera Pitch Rate';
            app.CameraPitchRateEditField_2 = uieditfield(app.InitialStatesPanel_2, 'numeric');
            app.degsLabel_10 = uilabel(app.InitialStatesPanel_2);
            app.degsLabel_10.Interpreter = 'latex';
            app.degsLabel_10.Text = '$deg/s$';
            app.CameraYawRateEditField_2Label = uilabel(app.InitialStatesPanel_2);
            app.CameraYawRateEditField_2Label.Text = 'Camera Yaw Rate';
            app.CameraYawRateEditField_2 = uieditfield(app.InitialStatesPanel_2, 'numeric');
            app.degsLabel_11 = uilabel(app.InitialStatesPanel_2);
            app.degsLabel_11.Interpreter = 'latex';
            app.degsLabel_11.Text = '$deg/s$';
            app.CopyButton = uibutton(app.InitialiseSimulationTab, 'push');
            app.CopyButton.ButtonPushedFcn = createCallbackFcn(app, @CopyButtonPushed, true);
            app.CopyButton.Text = 'Copy';
            app.InitiliaseCameraTab = uitab(app.TabGroup);
            app.InitiliaseCameraTab.Title = 'Initiliase Camera';
            app.InitialiseCameraPanel = uipanel(app.InitiliaseCameraTab);
            app.InitialiseCameraPanel.Title = 'Initialise Camera';
            app.ResolutionWidthEditFieldLabel = uilabel(app.InitialiseCameraPanel);
            app.ResolutionWidthEditFieldLabel.Text = 'Resolution Width';
            app.ResolutionWidthEditField = uieditfield(app.InitialiseCameraPanel, 'numeric');
            app.pLabel = uilabel(app.InitialiseCameraPanel);
            app.pLabel.Interpreter = 'latex';
            app.pLabel.Text = '$p$';
            app.ResolutionHeightEditFieldLabel = uilabel(app.InitialiseCameraPanel);
            app.ResolutionHeightEditFieldLabel.Text = 'Resolution Height';
            app.ResolutionHeightEditField = uieditfield(app.InitialiseCameraPanel, 'numeric');
            app.pLabel_2 = uilabel(app.InitialiseCameraPanel);
            app.pLabel_2.Interpreter = 'latex';
            app.pLabel_2.Text = '$p$';
            app.FocalLengthEditFieldLabel = uilabel(app.InitialiseCameraPanel);
            app.FocalLengthEditFieldLabel.Text = 'Focal Length';
            app.FocalLengthEditField = uieditfield(app.InitialiseCameraPanel, 'numeric');
            app.mLabel_4 = uilabel(app.InitialiseCameraPanel);
            app.mLabel_4.Interpreter = 'latex';
            app.mLabel_4.Text = '$m$';
            app.PixelSizeEditFieldLabel = uilabel(app.InitialiseCameraPanel);
            app.PixelSizeEditFieldLabel.Text = 'Pixel Size';
            app.PixelSizeEditField = uieditfield(app.InitialiseCameraPanel, 'numeric');
            app.PixelSizeEditField.ValueDisplayFormat = '%5.2f';
            app.mLabel_5 = uilabel(app.InitialiseCameraPanel);
            app.mLabel_5.Interpreter = 'latex';
            app.mLabel_5.Text = '$m$';
            app.AlphaEditField_2Label = uilabel(app.InitialiseCameraPanel);
            app.AlphaEditField_2Label.Enable = 'off';
            app.AlphaEditField_2Label.Text = 'Alpha';
            app.AlphaEditField = uieditfield(app.InitialiseCameraPanel, 'numeric');
            app.AlphaEditField.Enable = 'off';
            app.InitialiseETCameraPanel = uipanel(app.InitiliaseCameraTab);
            app.InitialiseETCameraPanel.Title = 'Initialise ET Camera';
            app.ResolutionWidthEditField_2Label = uilabel(app.InitialiseETCameraPanel);
            app.ResolutionWidthEditField_2Label.Text = 'Resolution Width';
            app.ResolutionWidthEditField_2 = uieditfield(app.InitialiseETCameraPanel, 'numeric');
            app.pLabel_3 = uilabel(app.InitialiseETCameraPanel);
            app.pLabel_3.Interpreter = 'latex';
            app.pLabel_3.Text = '$p$';
            app.ResolutionHeightEditField_2Label = uilabel(app.InitialiseETCameraPanel);
            app.ResolutionHeightEditField_2Label.Text = 'Resolution Height';
            app.ResolutionHeightEditField_2 = uieditfield(app.InitialiseETCameraPanel, 'numeric');
            app.pLabel_4 = uilabel(app.InitialiseETCameraPanel);
            app.pLabel_4.Interpreter = 'latex';
            app.pLabel_4.Text = '$p$';
            app.FocalLengthEditField_2Label = uilabel(app.InitialiseETCameraPanel);
            app.FocalLengthEditField_2Label.Text = 'Focal Length';
            app.FocalLengthEditField_2 = uieditfield(app.InitialiseETCameraPanel, 'numeric');
            app.mLabel_6 = uilabel(app.InitialiseETCameraPanel);
            app.mLabel_6.Interpreter = 'latex';
            app.mLabel_6.Text = '$m$';
            app.PixelSizeEditField_2Label = uilabel(app.InitialiseETCameraPanel);
            app.PixelSizeEditField_2Label.Text = 'Pixel Size';
            app.PixelSizeEditField_2 = uieditfield(app.InitialiseETCameraPanel, 'numeric');
            app.PixelSizeEditField_2.ValueDisplayFormat = '%5.2f';
            app.mLabel_7 = uilabel(app.InitialiseETCameraPanel);
            app.mLabel_7.Interpreter = 'latex';
            app.mLabel_7.Text = '$m$';
            app.AlphaEditFieldLabel = uilabel(app.InitialiseETCameraPanel);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.AlphaEditField_2 = uieditfield(app.InitialiseETCameraPanel, 'numeric');
            app.Label = uilabel(app.InitialiseETCameraPanel);
            app.Label.Interpreter = 'latex';
            app.Label.Text = '';
            app.LensDistortionsPanel = uipanel(app.InitiliaseCameraTab);
            app.LensDistortionsPanel.Title = 'Lens Distortions';
            app.RadialCheckBox = uicheckbox(app.LensDistortionsPanel);
            app.RadialCheckBox.Text = 'Radial';
            app.TangentialCheckBox = uicheckbox(app.LensDistortionsPanel);
            app.TangentialCheckBox.Text = 'Tangential';
            app.ChromaticCheckBox = uicheckbox(app.LensDistortionsPanel);
            app.ChromaticCheckBox.Text = 'Chromatic';
            app.k1EditFieldLabel = uilabel(app.LensDistortionsPanel);
            app.k1EditFieldLabel.Text = 'k1';
            app.k1EditField = uieditfield(app.LensDistortionsPanel, 'numeric');
            app.k1EditField.Value = 0.001;
            app.k2EditFieldLabel = uilabel(app.LensDistortionsPanel);
            app.k2EditFieldLabel.Text = 'k2';
            app.k2EditField = uieditfield(app.LensDistortionsPanel, 'numeric');
            app.k2EditField.Value = 0.001;
            app.k3EditFieldLabel = uilabel(app.LensDistortionsPanel);
            app.k3EditFieldLabel.Text = 'k3';
            app.k3EditField = uieditfield(app.LensDistortionsPanel, 'numeric');
            app.p1EditFieldLabel = uilabel(app.LensDistortionsPanel);
            app.p1EditFieldLabel.Text = 'p1';
            app.p1EditField = uieditfield(app.LensDistortionsPanel, 'numeric');
            app.p1EditField.Value = 0.001;
            app.p2EditFieldLabel = uilabel(app.LensDistortionsPanel);
            app.p2EditFieldLabel.Text = 'p2';
            app.p2EditField = uieditfield(app.LensDistortionsPanel, 'numeric');
            app.p2EditField.Value = 0.001;
            app.REditFieldLabel = uilabel(app.LensDistortionsPanel);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.LensDistortionsPanel, 'numeric');
            app.REditField.Value = 0.95;
            app.GEditFieldLabel = uilabel(app.LensDistortionsPanel);
            app.GEditFieldLabel.Text = 'G';
            app.GEditField = uieditfield(app.LensDistortionsPanel, 'numeric');
            app.GEditField.Value = 1;
            app.BEditFieldLabel = uilabel(app.LensDistortionsPanel);
            app.BEditFieldLabel.Text = 'B';
            app.BEditField = uieditfield(app.LensDistortionsPanel, 'numeric');
            app.BEditField.Value = 1.02;
            app.FeatureDetectionPanel = uipanel(app.InitiliaseCameraTab);
            app.FeatureDetectionPanel.Title = 'Feature Detection';
            app.NumberofFeaturesEditFieldLabel = uilabel(app.FeatureDetectionPanel);
            app.NumberofFeaturesEditFieldLabel.Text = 'Number of Features';
            app.NumberofFeaturesEditField = uieditfield(app.FeatureDetectionPanel, 'numeric');
            app.NumberofFeaturesEditField.Limits = [1 Inf];
            app.NumberofFeaturesEditField.Value = 20;
            app.FeatureDetectionMethodButtonGroup = uibuttongroup(app.FeatureDetectionPanel);
            app.FeatureDetectionMethodButtonGroup.Title = 'Feature Detection Method';
            app.SIFTButton = uitogglebutton(app.FeatureDetectionMethodButtonGroup);
            app.SIFTButton.Text = 'SIFT';
            app.SIFTButton.Value = true;
            app.SURFButton = uitogglebutton(app.FeatureDetectionMethodButtonGroup);
            app.SURFButton.Text = 'SURF';
            app.ORBButton = uitogglebutton(app.FeatureDetectionMethodButtonGroup);
            app.ORBButton.Text = 'ORB';
            app.CopyButton_2 = uibutton(app.InitiliaseCameraTab, 'push');
            app.CopyButton_2.ButtonPushedFcn = createCallbackFcn(app, @CopyButton_2Pushed, true);
            app.CopyButton_2.Text = 'Copy';
            app.InitialiseSensorsTab = uitab(app.TabGroup);
            app.InitialiseSensorsTab.Title = 'Initialise Sensors';
            app.InitialiseEarthTrackerPanel = uipanel(app.InitialiseSensorsTab);
            app.InitialiseEarthTrackerPanel.Title = 'Initialise Earth Tracker';
            app.EnableCheckBox = uicheckbox(app.InitialiseEarthTrackerPanel);
            app.EnableCheckBox.Text = 'Enable';
            app.EnableCheckBox.Value = true;
            app.SampleTimeEditField_3Label = uilabel(app.InitialiseEarthTrackerPanel);
            app.SampleTimeEditField_3Label.Text = 'Sample Time';
            app.SampleTimeEditField_3 = uieditfield(app.InitialiseEarthTrackerPanel, 'numeric');
            app.SampleTimeEditField_3.Value = 10;
            app.HzLabel_9 = uilabel(app.InitialiseEarthTrackerPanel);
            app.HzLabel_9.Interpreter = 'latex';
            app.HzLabel_9.Text = '$Hz$';
            app.NoiseEditFieldLabel = uilabel(app.InitialiseEarthTrackerPanel);
            app.NoiseEditFieldLabel.Text = 'Noise';
            app.NoiseEditField = uieditfield(app.InitialiseEarthTrackerPanel, 'numeric');
            app.NoiseEditField.Value = 10;
            app.mLabel_8 = uilabel(app.InitialiseEarthTrackerPanel);
            app.mLabel_8.Interpreter = 'latex';
            app.mLabel_8.Text = '$m$';
            app.InitialiseCoarseSunSensorPanel = uipanel(app.InitialiseSensorsTab);
            app.InitialiseCoarseSunSensorPanel.Title = 'Initialise Coarse Sun Sensor';
            app.NoiseEditField_5Label = uilabel(app.InitialiseCoarseSunSensorPanel);
            app.NoiseEditField_5Label.Text = 'Noise';
            app.NoiseEditField_5 = uieditfield(app.InitialiseCoarseSunSensorPanel, 'numeric');
            app.NoiseEditField_5.Value = 10;
            app.errorsensorLabel = uilabel(app.InitialiseCoarseSunSensorPanel);
            app.errorsensorLabel.Interpreter = 'latex';
            app.errorsensorLabel.Text = '$\%$ error/sensor';
            app.EnableCheckBox_5 = uicheckbox(app.InitialiseCoarseSunSensorPanel);
            app.EnableCheckBox_5.Text = 'Enable';
            app.EnableCheckBox_5.Value = true;
            app.HzLabel_11 = uilabel(app.InitialiseCoarseSunSensorPanel);
            app.HzLabel_11.Interpreter = 'latex';
            app.HzLabel_11.Text = '$Hz$';
            app.SampleTimeEditField_8Label = uilabel(app.InitialiseCoarseSunSensorPanel);
            app.SampleTimeEditField_8Label.Text = 'Sample Time';
            app.SampleTimeEditField_8 = uieditfield(app.InitialiseCoarseSunSensorPanel, 'numeric');
            app.SampleTimeEditField_8.Value = 10;
            app.InitialiseStarTrackerPanel = uipanel(app.InitialiseSensorsTab);
            app.InitialiseStarTrackerPanel.Title = 'Initialise Star Tracker';
            app.EnableCheckBox_2 = uicheckbox(app.InitialiseStarTrackerPanel);
            app.EnableCheckBox_2.Text = 'Enable';
            app.EnableCheckBox_2.Value = true;
            app.SampleTimeEditField_4Label = uilabel(app.InitialiseStarTrackerPanel);
            app.SampleTimeEditField_4Label.Text = 'Sample Time';
            app.SampleTimeEditField_4 = uieditfield(app.InitialiseStarTrackerPanel, 'numeric');
            app.SampleTimeEditField_4.Value = 1;
            app.HzLabel_4 = uilabel(app.InitialiseStarTrackerPanel);
            app.HzLabel_4.Interpreter = 'latex';
            app.HzLabel_4.Text = '$Hz$';
            app.NoiseEditField_2Label = uilabel(app.InitialiseStarTrackerPanel);
            app.NoiseEditField_2Label.Text = 'Noise';
            app.NoiseEditField_2 = uieditfield(app.InitialiseStarTrackerPanel, 'numeric');
            app.NoiseEditField_2.Value = 0.002;
            app.arcsecondsLabel = uilabel(app.InitialiseStarTrackerPanel);
            app.arcsecondsLabel.Interpreter = 'latex';
            app.arcsecondsLabel.Text = '$arcseconds$';
            app.InitialiseGPSPanel = uipanel(app.InitialiseSensorsTab);
            app.InitialiseGPSPanel.Title = 'Initialise GPS';
            app.EnableCheckBox_3 = uicheckbox(app.InitialiseGPSPanel);
            app.EnableCheckBox_3.Text = 'Enable';
            app.EnableCheckBox_3.Value = true;
            app.SampleTimeEditField_5Label = uilabel(app.InitialiseGPSPanel);
            app.SampleTimeEditField_5Label.Text = 'Sample Time';
            app.SampleTimeEditField_5 = uieditfield(app.InitialiseGPSPanel, 'numeric');
            app.SampleTimeEditField_5.Value = 10;
            app.HzLabel_6 = uilabel(app.InitialiseGPSPanel);
            app.HzLabel_6.Interpreter = 'latex';
            app.HzLabel_6.Text = '$Hz$';
            app.DriftRateEditFieldLabel = uilabel(app.InitialiseGPSPanel);
            app.DriftRateEditFieldLabel.Text = 'Drift Rate';
            app.NoiseEditField_3Label = uilabel(app.InitialiseGPSPanel);
            app.NoiseEditField_3Label.Text = 'Noise';
            app.NoiseEditField_3 = uieditfield(app.InitialiseGPSPanel, 'numeric');
            app.NoiseEditField_3.Value = 100;
            app.DriftRateEditField = uieditfield(app.InitialiseGPSPanel, 'numeric');
            app.DriftRateEditField.Value = 0.2;
            app.mLabel_9 = uilabel(app.InitialiseGPSPanel);
            app.mLabel_9.Interpreter = 'latex';
            app.mLabel_9.Text = '$m$';
            app.msqrthrLabel = uilabel(app.InitialiseGPSPanel);
            app.msqrthrLabel.Interpreter = 'latex';
            app.msqrthrLabel.Text = '$m/\sqrt{hr}$';
            app.InitialiseMagnetometerPanel = uipanel(app.InitialiseSensorsTab);
            app.InitialiseMagnetometerPanel.Title = 'Initialise Magnetometer';
            app.NoiseEditField_6Label = uilabel(app.InitialiseMagnetometerPanel);
            app.NoiseEditField_6Label.Text = 'Noise';
            app.NoiseEditField_6 = uieditfield(app.InitialiseMagnetometerPanel, 'numeric');
            app.NoiseEditField_6.Value = 5;
            app.nTLabel = uilabel(app.InitialiseMagnetometerPanel);
            app.nTLabel.Interpreter = 'latex';
            app.nTLabel.Text = '$nT$';
            app.EnableCheckBox_6 = uicheckbox(app.InitialiseMagnetometerPanel);
            app.EnableCheckBox_6.Text = 'Enable';
            app.EnableCheckBox_6.Value = true;
            app.HzLabel_10 = uilabel(app.InitialiseMagnetometerPanel);
            app.HzLabel_10.Interpreter = 'latex';
            app.HzLabel_10.Text = '$Hz$';
            app.SampleTimeEditField_7Label = uilabel(app.InitialiseMagnetometerPanel);
            app.SampleTimeEditField_7Label.Text = 'Sample Time';
            app.SampleTimeEditField_7 = uieditfield(app.InitialiseMagnetometerPanel, 'numeric');
            app.SampleTimeEditField_7.Value = 10;
            app.InitialiseGyroscopePanel = uipanel(app.InitialiseSensorsTab);
            app.InitialiseGyroscopePanel.Title = 'Initialise Gyroscope';
            app.EnableCheckBox_4 = uicheckbox(app.InitialiseGyroscopePanel);
            app.EnableCheckBox_4.Text = 'Enable';
            app.EnableCheckBox_4.Value = true;
            app.SampleTimeEditField_6Label = uilabel(app.InitialiseGyroscopePanel);
            app.SampleTimeEditField_6Label.Text = 'Sample Time';
            app.SampleTimeEditField_6 = uieditfield(app.InitialiseGyroscopePanel, 'numeric');
            app.SampleTimeEditField_6.Value = 50;
            app.HzLabel_8 = uilabel(app.InitialiseGyroscopePanel);
            app.HzLabel_8.Interpreter = 'latex';
            app.HzLabel_8.Text = '$Hz$';
            app.DriftRateEditField_2Label = uilabel(app.InitialiseGyroscopePanel);
            app.DriftRateEditField_2Label.Text = 'Drift Rate';
            app.NoiseEditField_4Label = uilabel(app.InitialiseGyroscopePanel);
            app.NoiseEditField_4Label.Text = 'Noise';
            app.NoiseEditField_4 = uieditfield(app.InitialiseGyroscopePanel, 'numeric');
            app.NoiseEditField_4.Value = 0.05;
            app.DriftRateEditField_2 = uieditfield(app.InitialiseGyroscopePanel, 'numeric');
            app.DriftRateEditField_2.Value = 1;
            app.degsLabel_13 = uilabel(app.InitialiseGyroscopePanel);
            app.degsLabel_13.Interpreter = 'latex';
            app.degsLabel_13.Text = '$deg/s$';
            app.degsqrthrLabel = uilabel(app.InitialiseGyroscopePanel);
            app.degsqrthrLabel.Interpreter = 'latex';
            app.degsqrthrLabel.Text = '$deg/\sqrt{hr}$';
            app.TuneFilterTab = uitab(app.TabGroup);
            app.TuneFilterTab.Title = 'Tune Filter';
            app.TuneFilterPanel = uipanel(app.TuneFilterTab);
            app.TuneFilterPanel.Title = 'Tune Filter';
            app.PositionLabel = uilabel(app.TuneFilterPanel);
            app.PositionLabel.Text = 'Position';
            app.r_xEditFieldLabel = uilabel(app.TuneFilterPanel);
            app.r_xEditFieldLabel.Interpreter = 'latex';
            app.r_xEditFieldLabel.Text = '$r_x$';
            app.r_xEditField = uieditfield(app.TuneFilterPanel, 'numeric');
            app.r_xEditField.Value = 100;
            app.mLabel = uilabel(app.TuneFilterPanel);
            app.mLabel.Interpreter = 'latex';
            app.mLabel.Text = '$m$';
            app.r_yEditFieldLabel = uilabel(app.TuneFilterPanel);
            app.r_yEditFieldLabel.Interpreter = 'latex';
            app.r_yEditFieldLabel.Text = '$r_y$';
            app.r_yEditField = uieditfield(app.TuneFilterPanel, 'numeric');
            app.r_yEditField.Value = 100;
            app.mLabel_2 = uilabel(app.TuneFilterPanel);
            app.mLabel_2.Interpreter = 'latex';
            app.mLabel_2.Text = '$m$';
            app.r_zEditFieldLabel = uilabel(app.TuneFilterPanel);
            app.r_zEditFieldLabel.Interpreter = 'latex';
            app.r_zEditFieldLabel.Text = '$r_z$';
            app.r_zEditField = uieditfield(app.TuneFilterPanel, 'numeric');
            app.r_zEditField.Value = 100;
            app.mLabel_3 = uilabel(app.TuneFilterPanel);
            app.mLabel_3.Interpreter = 'latex';
            app.mLabel_3.Text = '$m$';
            app.VelocityLabel = uilabel(app.TuneFilterPanel);
            app.VelocityLabel.Text = 'Velocity';
            app.v_xEditFieldLabel = uilabel(app.TuneFilterPanel);
            app.v_xEditFieldLabel.Interpreter = 'latex';
            app.v_xEditFieldLabel.Text = '$v_x$';
            app.v_xEditField = uieditfield(app.TuneFilterPanel, 'numeric');
            app.v_xEditField.Value = 50;
            app.msLabel = uilabel(app.TuneFilterPanel);
            app.msLabel.Interpreter = 'latex';
            app.msLabel.Text = '$m/s$';
            app.v_yEditFieldLabel = uilabel(app.TuneFilterPanel);
            app.v_yEditFieldLabel.Interpreter = 'latex';
            app.v_yEditFieldLabel.Text = '$v_y$';
            app.v_yEditField = uieditfield(app.TuneFilterPanel, 'numeric');
            app.v_yEditField.Value = 50;
            app.msLabel_2 = uilabel(app.TuneFilterPanel);
            app.msLabel_2.Interpreter = 'latex';
            app.msLabel_2.Text = '$m/s$';
            app.v_zEditFieldLabel = uilabel(app.TuneFilterPanel);
            app.v_zEditFieldLabel.Interpreter = 'latex';
            app.v_zEditFieldLabel.Text = '$v_z$';
            app.v_zEditField = uieditfield(app.TuneFilterPanel, 'numeric');
            app.v_zEditField.Value = 50;
            app.msLabel_3 = uilabel(app.TuneFilterPanel);
            app.msLabel_3.Interpreter = 'latex';
            app.msLabel_3.Text = '$m/s$';
            app.AttitudeLabel = uilabel(app.TuneFilterPanel);
            app.AttitudeLabel.Text = 'Attitude';
            app.q_sEditFieldLabel = uilabel(app.TuneFilterPanel);
            app.q_sEditFieldLabel.Interpreter = 'latex';
            app.q_sEditFieldLabel.Text = '$q_s$';
            app.q_sEditField = uieditfield(app.TuneFilterPanel, 'numeric');
            app.q_sEditField.Value = 5;
            app.q_xEditFieldLabel = uilabel(app.TuneFilterPanel);
            app.q_xEditFieldLabel.Interpreter = 'latex';
            app.q_xEditFieldLabel.Text = '$q_x$';
            app.q_xEditField = uieditfield(app.TuneFilterPanel, 'numeric');
            app.q_xEditField.Value = 5;
            app.qyLabel = uilabel(app.TuneFilterPanel);
            app.qyLabel.Interpreter = 'latex';
            app.qyLabel.Text = '$q_y$';
            app.q_yEditField = uieditfield(app.TuneFilterPanel, 'numeric');
            app.q_yEditField.Value = 5;
            app.q_zEditFieldLabel = uilabel(app.TuneFilterPanel);
            app.q_zEditFieldLabel.Interpreter = 'latex';
            app.q_zEditFieldLabel.Text = '$q_z$';
            app.q_zEditField = uieditfield(app.TuneFilterPanel, 'numeric');
            app.q_zEditField.Value = 5;
            app.AngularVelocityLabel = uilabel(app.TuneFilterPanel);
            app.AngularVelocityLabel.Text = 'Angular Velocity';
            app.omega_xEditFieldLabel = uilabel(app.TuneFilterPanel);
            app.omega_xEditFieldLabel.Interpreter = 'latex';
            app.omega_xEditFieldLabel.Text = '$\omega_x%';
            app.omega_xEditField = uieditfield(app.TuneFilterPanel, 'numeric');
            app.omega_xEditField.Value = 0.5;
            app.degsLabel_5 = uilabel(app.TuneFilterPanel);
            app.degsLabel_5.Interpreter = 'latex';
            app.degsLabel_5.Text = '$deg/s$';
            app.omega_yEditFieldLabel = uilabel(app.TuneFilterPanel);
            app.omega_yEditFieldLabel.Interpreter = 'latex';
            app.omega_yEditFieldLabel.Text = '$\omega_y$';
            app.omega_yEditField = uieditfield(app.TuneFilterPanel, 'numeric');
            app.omega_yEditField.Value = 0.5;
            app.degsLabel_6 = uilabel(app.TuneFilterPanel);
            app.degsLabel_6.Interpreter = 'latex';
            app.degsLabel_6.Text = '$deg/s$';
            app.omega_zEditFieldLabel = uilabel(app.TuneFilterPanel);
            app.omega_zEditFieldLabel.Interpreter = 'latex';
            app.omega_zEditFieldLabel.Text = '$\omega_z$';
            app.omega_zEditField = uieditfield(app.TuneFilterPanel, 'numeric');
            app.omega_zEditField.Value = 0.5;
            app.degsLabel_7 = uilabel(app.TuneFilterPanel);
            app.degsLabel_7.Interpreter = 'latex';
            app.degsLabel_7.Text = '$deg/s$';
            app.InitialiseCovarianceMatrixPanel = uipanel(app.TuneFilterTab);
            app.InitialiseCovarianceMatrixPanel.Title = 'Initialise Covariance Matrix';
            app.PositionLabel_2 = uilabel(app.InitialiseCovarianceMatrixPanel);
            app.PositionLabel_2.Text = 'Position';
            app.r_xEditField_2Label = uilabel(app.InitialiseCovarianceMatrixPanel);
            app.r_xEditField_2Label.Interpreter = 'latex';
            app.r_xEditField_2Label.Text = '$r_x$';
            app.r_xEditField_2 = uieditfield(app.InitialiseCovarianceMatrixPanel, 'numeric');
            app.r_xEditField_2.Value = 5000;
            app.mLabel_10 = uilabel(app.InitialiseCovarianceMatrixPanel);
            app.mLabel_10.Interpreter = 'latex';
            app.mLabel_10.Text = '$m$';
            app.r_yEditField_2Label = uilabel(app.InitialiseCovarianceMatrixPanel);
            app.r_yEditField_2Label.Interpreter = 'latex';
            app.r_yEditField_2Label.Text = '$r_y$';
            app.r_yEditField_2 = uieditfield(app.InitialiseCovarianceMatrixPanel, 'numeric');
            app.r_yEditField_2.Value = 5000;
            app.mLabel_11 = uilabel(app.InitialiseCovarianceMatrixPanel);
            app.mLabel_11.Interpreter = 'latex';
            app.mLabel_11.Text = '$m$';
            app.r_zEditField_2Label = uilabel(app.InitialiseCovarianceMatrixPanel);
            app.r_zEditField_2Label.Interpreter = 'latex';
            app.r_zEditField_2Label.Text = '$r_z$';
            app.r_zEditField_2 = uieditfield(app.InitialiseCovarianceMatrixPanel, 'numeric');
            app.r_zEditField_2.Value = 5000;
            app.mLabel_12 = uilabel(app.InitialiseCovarianceMatrixPanel);
            app.mLabel_12.Interpreter = 'latex';
            app.mLabel_12.Text = '$m$';
            app.VelocityLabel_2 = uilabel(app.InitialiseCovarianceMatrixPanel);
            app.VelocityLabel_2.Text = 'Velocity';
            app.v_xEditField_2Label = uilabel(app.InitialiseCovarianceMatrixPanel);
            app.v_xEditField_2Label.Interpreter = 'latex';
            app.v_xEditField_2Label.Text = '$v_x$';
            app.v_xEditField_2 = uieditfield(app.InitialiseCovarianceMatrixPanel, 'numeric');
            app.v_xEditField_2.Value = 500;
            app.msLabel_4 = uilabel(app.InitialiseCovarianceMatrixPanel);
            app.msLabel_4.Interpreter = 'latex';
            app.msLabel_4.Text = '$m/s$';
            app.v_yEditField_2Label = uilabel(app.InitialiseCovarianceMatrixPanel);
            app.v_yEditField_2Label.Interpreter = 'latex';
            app.v_yEditField_2Label.Text = '$v_y$';
            app.v_yEditField_2 = uieditfield(app.InitialiseCovarianceMatrixPanel, 'numeric');
            app.v_yEditField_2.Value = 500;
            app.msLabel_5 = uilabel(app.InitialiseCovarianceMatrixPanel);
            app.msLabel_5.Interpreter = 'latex';
            app.msLabel_5.Text = '$m/s$';
            app.v_zEditField_2Label = uilabel(app.InitialiseCovarianceMatrixPanel);
            app.v_zEditField_2Label.Interpreter = 'latex';
            app.v_zEditField_2Label.Text = '$v_z$';
            app.v_zEditField_2 = uieditfield(app.InitialiseCovarianceMatrixPanel, 'numeric');
            app.v_zEditField_2.Value = 500;
            app.msLabel_6 = uilabel(app.InitialiseCovarianceMatrixPanel);
            app.msLabel_6.Interpreter = 'latex';
            app.msLabel_6.Text = '$m/s$';
            app.AttitudeLabel_2 = uilabel(app.InitialiseCovarianceMatrixPanel);
            app.AttitudeLabel_2.Text = 'Attitude';
            app.q_sEditField_2Label = uilabel(app.InitialiseCovarianceMatrixPanel);
            app.q_sEditField_2Label.Interpreter = 'latex';
            app.q_sEditField_2Label.Text = '$q_s$';
            app.q_sEditField_2 = uieditfield(app.InitialiseCovarianceMatrixPanel, 'numeric');
            app.q_sEditField_2.Value = 10;
            app.q_xEditField_2Label = uilabel(app.InitialiseCovarianceMatrixPanel);
            app.q_xEditField_2Label.Interpreter = 'latex';
            app.q_xEditField_2Label.Text = '$q_x$';
            app.q_xEditField_2 = uieditfield(app.InitialiseCovarianceMatrixPanel, 'numeric');
            app.q_xEditField_2.Value = 10;
            app.q_yEditField_2Label = uilabel(app.InitialiseCovarianceMatrixPanel);
            app.q_yEditField_2Label.Interpreter = 'latex';
            app.q_yEditField_2Label.Text = '$q_y$';
            app.q_yEditField_2 = uieditfield(app.InitialiseCovarianceMatrixPanel, 'numeric');
            app.q_yEditField_2.Value = 10;
            app.q_zEditField_2Label = uilabel(app.InitialiseCovarianceMatrixPanel);
            app.q_zEditField_2Label.Interpreter = 'latex';
            app.q_zEditField_2Label.Text = '$q_z$';
            app.q_zEditField_2 = uieditfield(app.InitialiseCovarianceMatrixPanel, 'numeric');
            app.q_zEditField_2.Value = 10;
            app.AngularVelocityLabel_2 = uilabel(app.InitialiseCovarianceMatrixPanel);
            app.AngularVelocityLabel_2.Text = 'Angular Velocity';
            app.omega_xEditField_2Label = uilabel(app.InitialiseCovarianceMatrixPanel);
            app.omega_xEditField_2Label.Interpreter = 'latex';
            app.omega_xEditField_2Label.Text = '$\omega_x%';
            app.omega_xEditField_2 = uieditfield(app.InitialiseCovarianceMatrixPanel, 'numeric');
            app.omega_xEditField_2.Value = 1;
            app.degsLabel_14 = uilabel(app.InitialiseCovarianceMatrixPanel);
            app.degsLabel_14.Interpreter = 'latex';
            app.degsLabel_14.Text = '$deg/s$';
            app.omega_yEditField_2Label = uilabel(app.InitialiseCovarianceMatrixPanel);
            app.omega_yEditField_2Label.Interpreter = 'latex';
            app.omega_yEditField_2Label.Text = '$\omega_y$';
            app.omega_yEditField_2 = uieditfield(app.InitialiseCovarianceMatrixPanel, 'numeric');
            app.omega_yEditField_2.Value = 1;
            app.degsLabel_15 = uilabel(app.InitialiseCovarianceMatrixPanel);
            app.degsLabel_15.Interpreter = 'latex';
            app.degsLabel_15.Text = '$deg/s$';
            app.omega_zEditField_2Label = uilabel(app.InitialiseCovarianceMatrixPanel);
            app.omega_zEditField_2Label.Interpreter = 'latex';
            app.omega_zEditField_2Label.Text = '$\omega_z$';
            app.omega_zEditField_2 = uieditfield(app.InitialiseCovarianceMatrixPanel, 'numeric');
            app.omega_zEditField_2.Value = 1;
            app.degsLabel_16 = uilabel(app.InitialiseCovarianceMatrixPanel);
            app.degsLabel_16.Interpreter = 'latex';
            app.degsLabel_16.Text = '$deg/s$';
            app.CopyButton_3 = uibutton(app.TuneFilterTab, 'push');
            app.CopyButton_3.ButtonPushedFcn = createCallbackFcn(app, @CopyButton_3Pushed, true);
            app.CopyButton_3.Text = 'Copy';
            app.RunSimulationTab = uitab(app.TabGroup);
            app.RunSimulationTab.Title = 'Run Simulation';
            app.RunSimulationButton = uibutton(app.RunSimulationTab, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.StopSimulationButton = uibutton(app.RunSimulationTab, 'push');
            app.StopSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StopSimulationButtonPushed, true);
            app.StopSimulationButton.Enable = 'off';
            app.StopSimulationButton.Text = 'Stop Simulation';
            app.SaveImagesCheckBox = uicheckbox(app.RunSimulationTab);
            app.SaveImagesCheckBox.Text = 'Save Images';
            app.ViewResultsTab = uitab(app.TabGroup);
            app.ViewResultsTab.Title = 'View Results';
            app.PlotSystemPanel = uipanel(app.ViewResultsTab);
            app.PlotSystemPanel.Title = 'Plot System';
            app.hatxButton = uibutton(app.PlotSystemPanel, 'push');
            app.hatxButton.ButtonPushedFcn = createCallbackFcn(app, @hatxButtonPushed, true);
            app.hatxButton.Interpreter = 'latex';
            app.hatxButton.Text = '$\hat{x}$';
            app.yButton = uibutton(app.PlotSystemPanel, 'push');
            app.yButton.ButtonPushedFcn = createCallbackFcn(app, @yButtonPushed, true);
            app.yButton.Interpreter = 'latex';
            app.yButton.Text = '$y$';
            app.PButton = uibutton(app.PlotSystemPanel, 'push');
            app.PButton.ButtonPushedFcn = createCallbackFcn(app, @PButtonPushed, true);
            app.PButton.Interpreter = 'latex';
            app.PButton.Text = '$P$';
            app.xButton = uibutton(app.PlotSystemPanel, 'push');
            app.xButton.ButtonPushedFcn = createCallbackFcn(app, @xButtonPushed, true);
            app.xButton.Interpreter = 'latex';
            app.xButton.Text = '$x$';
            app.PlotSensorsPanel = uipanel(app.ViewResultsTab);
            app.PlotSensorsPanel.Title = 'Plot Sensors';
            app.z_CSSButton = uibutton(app.PlotSensorsPanel, 'push');
            app.z_CSSButton.ButtonPushedFcn = createCallbackFcn(app, @z_CSSButtonPushed, true);
            app.z_CSSButton.Interpreter = 'latex';

            app.z_CSSButton.Text = '$z_{CSS}$';
            app.z_GPSButton = uibutton(app.PlotSensorsPanel, 'push');
            app.z_GPSButton.ButtonPushedFcn = createCallbackFcn(app, @z_GPSButtonPushed, true);
            app.z_GPSButton.Interpreter = 'latex';
            app.z_GPSButton.Text = '$z_{GPS}$';
            app.z_GYRButton = uibutton(app.PlotSensorsPanel, 'push');
            app.z_GYRButton.ButtonPushedFcn = createCallbackFcn(app, @z_GYRButtonPushed, true);
            app.z_GYRButton.Interpreter = 'latex';
            app.z_GYRButton.Text = '$z_{GYR}$';
            app.z_STButton = uibutton(app.PlotSensorsPanel, 'push');
            app.z_STButton.ButtonPushedFcn = createCallbackFcn(app, @z_STButtonPushed, true);
            app.z_STButton.Interpreter = 'latex';
            app.z_STButton.Text = '$z_{ST}$';
            app.z_MAGButton = uibutton(app.PlotSensorsPanel, 'push');
            app.z_MAGButton.ButtonPushedFcn = createCallbackFcn(app, @z_MAGButtonPushed, true);
            app.z_MAGButton.Interpreter = 'latex';
            app.z_MAGButton.Text = '$z_{MAG}$';
            app.hatz_GPSButton = uibutton(app.PlotSensorsPanel, 'push');
            app.hatz_GPSButton.Interpreter = 'latex';
            app.hatz_GPSButton.Text = '$\hat{z}_{GPS}$';
            app.y_GPSButton = uibutton(app.PlotSensorsPanel, 'push');
            app.y_GPSButton.Interpreter = 'latex';
            app.y_GPSButton.Text = '$y_{GPS}$';
            app.hatz_GYRButton = uibutton(app.PlotSensorsPanel, 'push');
            app.hatz_GYRButton.Interpreter = 'latex';
            app.hatz_GYRButton.Text = '$\hat{z}_{GYR}$';
            app.y_GYRButton = uibutton(app.PlotSensorsPanel, 'push');
            app.y_GYRButton.Interpreter = 'latex';
            app.y_GYRButton.Text = '$y_{GYR}$';
            app.hatz_STButton = uibutton(app.PlotSensorsPanel, 'push');
            app.hatz_STButton.Interpreter = 'latex';
            app.hatz_STButton.Text = '$\hat{z}_{ST}$';
            app.y_STButton = uibutton(app.PlotSensorsPanel, 'push');
            app.y_STButton.Interpreter = 'latex';
            app.y_STButton.Text = '$y_{ST}$';
            app.hatz_CSSButton = uibutton(app.PlotSensorsPanel, 'push');
            app.hatz_CSSButton.Interpreter = 'latex';
            app.hatz_CSSButton.Text = '$\hat{z}_{CSS}$';
            app.y_CSSButton = uibutton(app.PlotSensorsPanel, 'push');
            app.y_CSSButton.Interpreter = 'latex';
            app.y_CSSButton.Text = '$y_{CSS}$';
            app.hatz_MAGButton = uibutton(app.PlotSensorsPanel, 'push');
            app.hatz_MAGButton.Interpreter = 'latex';
            app.hatz_MAGButton.Text = '$\hat{z}_{MAG}$';
            app.y_MAGButton = uibutton(app.PlotSensorsPanel, 'push');
            app.y_MAGButton.Interpreter = 'latex';
            app.y_MAGButton.Text = '$y_{MAG}$';
            app.z_ETButton = uibutton(app.PlotSensorsPanel, 'push');
            app.z_ETButton.Interpreter = 'latex';
            app.z_ETButton.Text = '$z_{ET}$';
            app.y_ETButton = uibutton(app.PlotSensorsPanel, 'push');
            app.y_ETButton.Interpreter = 'latex';
            app.y_ETButton.Text = '$y_{ET}$';
            app.hatz_ETButton = uibutton(app.PlotSensorsPanel, 'push');
            app.hatz_ETButton.Interpreter = 'latex';
            app.hatz_ETButton.Text = '$\hat{z}_{ET}$';
            app.Panel = uipanel(app.ViewResultsTab);
            app.Panel.Title = 'Panel';
            app.K_ETButton = uibutton(app.Panel, 'push');
            app.K_ETButton.Interpreter = 'latex';
            app.K_ETButton.Text = '$K_{ET}$';
            app.K_GPSButton = uibutton(app.Panel, 'push');
            app.K_GPSButton.Interpreter = 'latex';
            app.K_GPSButton.Text = '$K_{GPS}$';
            app.K_GYRButton = uibutton(app.Panel, 'push');
            app.K_GYRButton.Interpreter = 'latex';
            app.K_GYRButton.Text = '$K_{GYR}$';
            app.K_STButton = uibutton(app.Panel, 'push');
            app.K_STButton.Interpreter = 'latex';
            app.K_STButton.Text = '$K_{ST}$';
            app.K_CSSButton = uibutton(app.Panel, 'push');
            app.K_CSSButton.Interpreter = 'latex';
            app.K_CSSButton.Text = '$K_{CSS}$';
            app.K_MAGButton = uibutton(app.Panel, 'push');
            app.K_MAGButton.Interpreter = 'latex';
            app.K_MAGButton.Text = '$K_{MAG}$';
            app.H_ETButton = uibutton(app.Panel, 'push');
            app.H_ETButton.Interpreter = 'latex';
            app.H_ETButton.Text = '$H_{ET}$';
            app.H_GPSButton = uibutton(app.Panel, 'push');
            app.H_GPSButton.Interpreter = 'latex';
            app.H_GPSButton.Text = '$H_{GPS}$';
            app.H_GYRButton = uibutton(app.Panel, 'push');
            app.H_GYRButton.Interpreter = 'latex';
            app.H_GYRButton.Text = '$H_{GYR}$';
            app.H_STButton = uibutton(app.Panel, 'push');
            app.H_STButton.Interpreter = 'latex';
            app.H_STButton.Text = '$H_{ST}$';
            app.H_CSSButton = uibutton(app.Panel, 'push');
            app.H_CSSButton.Interpreter = 'latex';
            app.H_CSSButton.Text = '$H_{CSS}$';
            app.H_MAGButton = uibutton(app.Panel, 'push');
            app.H_MAGButton.Interpreter = 'latex';
            app.H_MAGButton.Text = '$H_{MAG}$';
            app.PlotImagesPanel = uipanel(app.ViewResultsTab);
            app.PlotImagesPanel.Title = 'Plot Images';
            app.ViewVideoButton = uibutton(app.PlotImagesPanel, 'push');
            app.ViewVideoButton.ButtonPushedFcn = createCallbackFcn(app, @ViewVideoButtonPushed, true);
            app.ViewVideoButton.Text = 'View Video';
            app.ViewFeatureVideoButton = uibutton(app.PlotImagesPanel, 'push');
            app.ViewFeatureVideoButton.ButtonPushedFcn = createCallbackFcn(app, @ViewFeatureVideoButtonPushed, true);
            app.ViewFeatureVideoButton.Text = 'View Feature Video';
            app.ViewDistortedVideoButton = uibutton(app.PlotImagesPanel, 'push');
            app.ViewDistortedVideoButton.ButtonPushedFcn = createCallbackFcn(app, @ViewDistortedVideoButtonPushed, true);
            app.ViewDistortedVideoButton.Text = 'View Distorted Video';
            app.EstimatedVideoButton = uibutton(app.PlotImagesPanel, 'push');
            app.EstimatedVideoButton.Text = 'Estimated Video';
            app.PlotVisualPanel = uipanel(app.ViewResultsTab);
            app.PlotVisualPanel.Title = 'Plot Visual';
            app.Plot3DPositionVisualisationButton = uibutton(app.PlotVisualPanel, 'push');
            app.Plot3DPositionVisualisationButton.ButtonPushedFcn = createCallbackFcn(app, @Plot3DPositionVisualisationButtonPushed, true);
            app.Plot3DPositionVisualisationButton.Text = 'Plot 3D Position Visualisation';
            app.Plot3DAttitudeVisualisationButton = uibutton(app.PlotVisualPanel, 'push');
            app.Plot3DAttitudeVisualisationButton.Text = 'Plot 3D Attitude Visualisation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Masters

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end